classdef easyWand6 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    easyWandUIFigure                matlab.ui.Figure
    ProjectMenu                     matlab.ui.container.Menu
    NewprojectMenu                  matlab.ui.container.Menu
    LoadasavedprojectMenu           matlab.ui.container.Menu
    SavethisprojectMenu             matlab.ui.container.Menu
    ExportcameraprofileMenu         matlab.ui.container.Menu
    ExportDLTcoefficientsMenu       matlab.ui.container.Menu
    ExporttoworkspaceMenu           matlab.ui.container.Menu
    QuitMenu                        matlab.ui.container.Menu
    DataMenu                        matlab.ui.container.Menu
    LoadpairedpointsMenu            matlab.ui.container.Menu
    LoadunpairedpointsMenu          matlab.ui.container.Menu
    LoadalignmentfileMenu           matlab.ui.container.Menu
    LoadacameraprofileMenu          matlab.ui.container.Menu
    SetimageoriginMenu              matlab.ui.container.Menu
    OperationsMenu                  matlab.ui.container.Menu
    computecalibrationMenu          matlab.ui.container.Menu
    clearoutlierdesignationsMenu    matlab.ui.container.Menu
    identifyoutliersandrecalibrateMenu  matlab.ui.container.Menu
    restoreoriginalcameraparametersMenu  matlab.ui.container.Menu
    clearmessagesMenu               matlab.ui.container.Menu
    HelpMenu                        matlab.ui.container.Menu
    AboutMenu                       matlab.ui.container.Menu
    LicenseMenu                     matlab.ui.container.Menu
    ManualMenu                      matlab.ui.container.Menu
    CheckforupdateMenu              matlab.ui.container.Menu
    GeteasySBAsourcecodeMenu        matlab.ui.container.Menu
    Getefficient_pnpsourcecodeMenu  matlab.ui.container.Menu
    msgTxtArea                      matlab.ui.control.TextArea
    TabGroup                        matlab.ui.container.TabGroup
    PairedpointsTab                 matlab.ui.container.Tab
    ShowpointsfromDropDown_2        matlab.ui.control.DropDown
    ShowpointsfromDropDown_2Label   matlab.ui.control.Label
    PairedpointsfileEditField       matlab.ui.control.EditField
    PairedpointsfileEditFieldLabel  matlab.ui.control.Label
    pairedPointsTable               matlab.ui.control.Table
    InterpointdistanceEditField     matlab.ui.control.NumericEditField
    InterpointdistanceEditFieldLabel  matlab.ui.control.Label
    UnpairedpointsTab               matlab.ui.container.Tab
    ShowpointsfromDropDown          matlab.ui.control.DropDown
    ShowpointsfromDropDownLabel     matlab.ui.control.Label
    UnpairedpointsfileEditField     matlab.ui.control.EditField
    UnpairedpointsfileEditFieldLabel  matlab.ui.control.Label
    unpairedPointsTable             matlab.ui.control.Table
    AlignmentPointsTab              matlab.ui.container.Tab
    ShowalignmentpointsfromDropDown  matlab.ui.control.DropDown
    ShowalignmentpointsfromDropDownLabel  matlab.ui.control.Label
    alignmentPointsTable            matlab.ui.control.Table
    AlignmentsettingsPanel          matlab.ui.container.Panel
    NosettingsforthisalignmenttypeLabel  matlab.ui.control.TextArea
    ElevationangleofthelastcameradegreesEditField  matlab.ui.control.NumericEditField
    ElevationangleofthelastcameradegreesEditFieldLabel  matlab.ui.control.Label
    AzimuthangleofthelastcameradegreesEditField  matlab.ui.control.NumericEditField
    AzimuthangleofthelastcameradegreesEditFieldLabel  matlab.ui.control.Label
    CamerarecordingfrequencyHzEditField  matlab.ui.control.NumericEditField
    CamerarecordingfrequencyHzEditFieldLabel  matlab.ui.control.Label
    AlignmentfileEditField          matlab.ui.control.EditField
    AlignmentfileEditFieldLabel     matlab.ui.control.Label
    AlignmenttypeDropDown           matlab.ui.control.DropDown
    AlignmenttypeDropDownLabel      matlab.ui.control.Label
    ScaleTab                        matlab.ui.container.Tab
    CameraDistanceTable             matlab.ui.control.Table
    SetscenescalefrommeasuredcameracameradistancesCheckBox  matlab.ui.control.CheckBox
    OptimizationTab                 matlab.ui.container.Tab
    LensdistortionDropDown          matlab.ui.control.DropDown
    LensdistortionDropDownLabel     matlab.ui.control.Label
    IntrinsicparamatersDropDown     matlab.ui.control.DropDown
    IntrinsicparamatersDropDownLabel  matlab.ui.control.Label
    DescriptionofoptimizationoperationsTextArea  matlab.ui.control.TextArea
    DescriptionofoptimizationoperationsTextAreaLabel  matlab.ui.control.Label
    cameraTable                     matlab.ui.control.Table
    plotAxes                        matlab.ui.control.UIAxes
  end


  properties (Access = private)
    wandPts % wand points struct with filename, path, data
    bkgPts % background points struct
    axisPts % alignment points struct
    extrinsics % struct with extrinsics.poses and extrinsics.positions, both cell arrays
    numCams = 1 % number of cameras in this project
    version = [6 0 0]; % version information
    wandScore % calibration wand score
    distanceScore % calibration camera distance score
    rmse % calibration summary rmse for each camera
    dltcoefs % calibration DLT coefficients
    intrinsics % processed camera intrinsics
    licenseText = {... % License text
      'license.txt for the easyWand6 app', ...
      ' ', ...
      'Copyright 2025 Tyson Hedrick', ...
      ' ', ...
      'License: BSD-3-Clause', ...
      'Redistribution and use in source and binary forms, with or without modification, are permitted provided that the following conditions are met:', ...
      '1. Redistributions of source code must retain the above copyright notice, this list of conditions and the following disclaimer.', ...
      '2. Redistributions in binary form must reproduce the above copyright notice, this list of conditions and the following disclaimer in the documentation and/or other materials provided with the distribution.', ...
      '3. Neither the name of the copyright holder nor the names of its contributors may be used to endorse or promote products derived from this software without specific prior written permission.', ...
      'THIS SOFTWARE IS PROVIDED BY THE COPYRIGHT HOLDERS AND CONTRIBUTORS "AS IS" AND ANY EXPRESS OR IMPLIED WARRANTIES, INCLUDING, BUT NOT LIMITED TO, THE IMPLIED WARRANTIES OF MERCHANTABILITY ', ...
      'AND FITNESS FOR A PARTICULAR PURPOSE ARE DISCLAIMED. IN NO EVENT SHALL THE COPYRIGHT HOLDER OR CONTRIBUTORS BE LIABLE FOR ANY DIRECT, INDIRECT, INCIDENTAL, SPECIAL, EXEMPLARY, OR CONSEQUENTIAL ', ...
      'DAMAGES (INCLUDING, BUT NOT LIMITED TO, PROCUREMENT OF SUBSTITUTE GOODS OR SERVICES; LOSS OF USE, DATA, OR PROFITS; OR BUSINESS INTERRUPTION) HOWEVER CAUSED AND ON ANY THEORY OF LIABILITY, WHETHER ', ...
      'IN CONTRACT, STRICT LIABILITY, OR TORT (INCLUDING NEGLIGENCE OR OTHERWISE) ARISING IN ANY WAY OUT OF THE USE OF THIS SOFTWARE, EVEN IF ADVISED OF THE POSSIBILITY OF SUCH DAMAGE.' ...
      '',...
      '',...
      'easyWand6 makes internal use of and is distributed with the EFFICIENT_PNP and easySBA routines, both licensed under version 3 of the GNU General Public License as published by the'...
      'Free Software Foundation. Source code bundles for both of these packages can be saved out from the easyWand6 app using the Get ... source code entries in the Help menu.'
      };
    imageOriginLowerLeft = 1; % Sets the assumed location of the image coordinate origin, either upper left or lower left (i.e. imageOriginLowerLeft = 1).
    msg % Cell array of the messages displayed in the main pane text box
    cameraTableDataBak % Container for the backup of the camera table data
  end


  methods (Access = private)

    function [] = plot3DReconstruct(app)
      % plot the scene in the GUI figure

      cla(app.plotAxes);  % Clear figure axes
      hold(app.plotAxes,'on'); % set hold mode
      meanpt=inanmean([app.wandPts.xyz(:,1:3);app.wandPts.xyz(:,4:6);app.bkgPts.xyz])';  % Get the centroid for scale

      % Plot the wand points in blue
      w1=app.wandPts.xyz(:,1:3);
      w2=app.wandPts.xyz(:,4:6);

      plot3(app.plotAxes,w1(:,1),w1(:,2),w1(:,3),'b.');
      plot3(app.plotAxes,w2(:,1),w2(:,2),w2(:,3),'b.')
      idx=find(isnan(w1(:,1))==false & isnan(w2(:,1))==false);
      for i=1:numel(idx)
        plot3(app.plotAxes,[w1(idx(i),1);w2(idx(i),1)],[w1(idx(i),2);w2(idx(i),2)],...
          [w1(idx(i),3);w2(idx(i),3)],'b-');
      end

      % grab the background points and plot them in green
      bkgPts=app.bkgPts.xyz;
      plot3(app.plotAxes,bkgPts(:,1),bkgPts(:,2),bkgPts(:,3),'g.')


      % grab the axis points and plot them in red
      axPts=app.axisPts.xyz;
      plot3(app.plotAxes,axPts(:,1),axPts(:,2),axPts(:,3),'mo')
      if sum(isfinite(axPts(:,1)))<5
        for i=2:size(axPts,1)
          plot3(app.plotAxes,[axPts(1,1);axPts(i,1)],[axPts(1,2);axPts(i,2)],...
            [axPts(1,3);axPts(i,3)],'r-')
        end
      end


      % get camera position and orientation
      for i=1:numel(app.extrinsics.poses)
        %[xyz,T] = DLTcameraPosition(app.dltcoefs(:,i));
        xyz=app.extrinsics.positions{i};
        foo=[1,0,0;0,1,0;0,0,-1];
        %pose=app.extrinsics.poses{i}';
        pose=-app.extrinsics.poses{i}'*foo;
        xyz=(-app.extrinsics.poses{i}'*xyz')'; % transform position for pose
        T = [pose,xyz'*0;xyz,1];
        % Primary cameras in black, secondary cameras in red
        %if uda.calibrationMode(i)
        plot3(app.plotAxes,xyz(1),xyz(2),xyz(3),'ks','markerFaceColor','k','markerSize',6)
        text(app.plotAxes,xyz(1),xyz(2),xyz(3),sprintf('    Camera %.0f',i),'Color','k')

        camVec=T*[0;0;(norm(xyz-meanpt)/2);0]; % a look vector for the camera
        plot3(app.plotAxes,[xyz(1);xyz(1)+camVec(1)],[xyz(2);xyz(2)+camVec(2)],...
          [xyz(3);xyz(3)+camVec(3)],'k-')

      end
      xlabel(app.plotAxes,'X'); ylabel(app.plotAxes,'Y'); zlabel(app.plotAxes,'Z');
      axis(app.plotAxes,'equal');
      rotate3d(app.plotAxes,'on');
      hold(app.plotAxes,'off');
      view(app.plotAxes,3);


      return
    end

    function [] = updateUnpairedPointsGrid(app)
      % updates the unpaired points UI table
      cam=app.ShowpointsfromDropDown.Value;
      app.unpairedPointsTable.Data=table(app.bkgPts.ID,app.bkgPts.data(:,cam*2-1),...
        app.bkgPts.data(:,cam*2),app.bkgPts.xyz(:,1),app.bkgPts.xyz(:,2),...
        app.bkgPts.xyz(:,3),app.bkgPts.rmse,app.bkgPts.outlier);
    end

    function [] = dlt_computeCoefficients(app)

      % function  [c,rmse] = dlt_computeCoefficients(frame,camPts)
      %
      % A basic implementation of 11 parameter DLT
      %
      % Inputs:
      %  frame - an array of x,y,z calibration point coordinates
      %  camPts - an array of u,v pixel coordinates from the camera
      %
      % Outputs:
      %  c - the 11 DLT coefficients
      %  rmse - root mean square error for the reconstruction; units =
      %
      % Notes - frame and camPts must have the same number of rows.  A minimum of
      % 6 rows are required to compute the coefficients.  The frame points must
      % not all lie within a single plane
      %
      % Ty Hedrick

      % gather xyz data
      baseFrame=[app.wandPts.xyz(:,1:3);app.wandPts.xyz(:,4:6);app.bkgPts.xyz]; % note - outlier points have a NaN xyz
      %baseFrame=baseFrame-inanmean(baseFrame); % need to move the DLT origin away from the base camera

      for C=1:app.numCams
        frame=baseFrame;
        camPts=[app.wandPts.data(:,C*2-1:C*2);...
          app.wandPts.data(:,(C*2-1:C*2)+2*app.numCams);...
          app.bkgPts.data(:,C*2-1:C*2)];

        % undistort the camera points if necessary
        %camPts(isfinite(camPts(:,1)),:)=undistortPoints(camPts(isfinite(camPts(:,1)),:),app.intrinsics(C));
        camPts(isfinite(camPts(:,1)),:)=undistortFast(app,camPts(isfinite(camPts(:,1)),:),app.intrinsics(C));

        % check for any NaN rows (missing data) in the frame or camPts
        ndx=find(sum(isnan([frame,camPts]),2)>0);

        % remove any missing data rows
        frame(ndx,:)=[];
        camPts(ndx,:)=[];

        % re-arrange the frame matrix to facilitate the linear least squares
        % solution
        M=zeros(size(frame,1)*2,11);
        for i=1:size(frame,1)
          M(2*i-1,1:3)=frame(i,1:3);
          M(2*i,5:7)=frame(i,1:3);
          M(2*i-1,4)=1;
          M(2*i,8)=1;
          M(2*i-1,9:11)=frame(i,1:3).*-camPts(i,1);
          M(2*i,9:11)=frame(i,1:3).*-camPts(i,2);
        end

        % re-arrange the camPts array for the linear solution
        camPtsF=reshape(flipud(rot90(camPts)),numel(camPts),1);

        % get the linear solution to the 11 parameters
        c=linsolve(M,camPtsF);

        % compute the position of the frame in u,v coordinates given the linear
        % solution from the previous line
        Muv=[];
        Muv(:,1)=(frame(:,1).*c(1)+frame(:,2).*c(2)+frame(:,3).*c(3)+c(4))./ ...
          (frame(:,1).*c(9)+frame(:,2).*c(10)+frame(:,3).*c(11)+1);
        Muv(:,2)=(frame(:,1).*c(5)+frame(:,2).*c(6)+frame(:,3).*c(7)+c(8))./ ...
          (frame(:,1).*c(9)+frame(:,2).*c(10)+frame(:,3).*c(11)+1);

        % compute the root mean square error between the ideal frame u,v and the
        % recorded frame u,v
        app.rmse(C)=(sum(sum((Muv-camPts).^2))./numel(camPts))^0.5;
        app.dltcoefs(:,C)=c;
      end
    end

    function [coefs2] = dlt_convertCoefficients(app)
      eTrans=[[eye(3),1e1*[1,10,10]'];[0,0,0,1]]; % change camera position & orientation for numeric conditioning
      %eTrans=[[eye(3),[0.0022   -0.0386    0.4174]';[0,0,0,1]]];
      % identity matrix with extra column for compatibility with 4x4 transforms
      m=[eye(3),[0;0;0]];

      for i=1:app.numCams
        %K=[F(i),0,pp(i*2-1);0,F(i),pp(i*2);0,0,1];
        K=[app.intrinsics(i).FocalLength(1),0,app.intrinsics(i).PrincipalPoint(1); ...
          0,app.intrinsics(i).FocalLength(1),app.intrinsics(i).PrincipalPoint(2); ...
          0,0,1];

        % camera rotations + translation as a 4x4 transform matrix
        %P1=[R(:,:,i),tv(:,:,i)'];
        %P1=[app.extrinsics.poses{i},app.extrinsics.poses{i}*app.extrinsics.positions{i}'];% best so far
        P1=[app.extrinsics.poses{i},app.extrinsics.poses{i}*-app.extrinsics.positions{i}'];% best so far
        %
        % P1=[app.extrinsics.poses{i}',(app.extrinsics.positions{i}*app.extrinsics.poses{i}')'];
        %P1=[app.extrinsics.poses{i},app.extrinsics.positions{i}'];
        P1e=[P1;[0,0,0,1]];

        %coefsraw=K*m*P1e*eTrans;
        coefsraw=K*m*P1e;
        coefs(:,i)=reshape(coefsraw',12,1);

        coefs2(:,i)=coefs(:,i)./coefs(end,i);
      end
      coefs2=coefs2(1:11,:);
    end

    function [] = updatePairedPointsGrid(app)
      % updates the unpaired points UI table
      camOpt=app.ShowpointsfromDropDown_2.Value;
      cam=ceil(camOpt/2);
      ptOff=abs(mod(camOpt,2)-1)*app.numCams*2; % uv array offset for point 2
      xyzOff=abs(mod(camOpt,2)-1)*3; % xyz array offset for point 2
      sOff=abs(mod(camOpt,2)-1)+1; % offset for 2-column arrays
      dt=table(app.wandPts.ID,app.wandPts.data(:,cam*2-1+ptOff),...
        app.wandPts.data(:,cam*2+ptOff),app.wandPts.xyz(:,1+xyzOff), ...
        app.wandPts.xyz(:,2+xyzOff), app.wandPts.xyz(:,3+xyzOff), ...
        app.wandPts.rmse(:,sOff),app.wandPts.wandLength,...
        app.wandPts.outlier(:,sOff));
      app.pairedPointsTable.Data=dt;
    end

    function [] = updateAlignmentOptionVisibility(app)
      % app.AlignmenttypeDropDown.Value options
      % 1 = 4 point axis
      % 2 = 3 point axis
      % 3 = plane
      % 4 = gravity
      % 5 = known azimuth and elevation
      % 6 = known 3D points
      if app.AlignmenttypeDropDown.Value==5 % azimuth & elevation
        app.NosettingsforthisalignmenttypeLabel.Visible=false;
        app.CamerarecordingfrequencyHzEditField.Visible=false;
        app.CamerarecordingfrequencyHzEditFieldLabel.Visible=false;
        app.AzimuthangleofthelastcameradegreesEditField.Visible=true;
        app.AzimuthangleofthelastcameradegreesEditFieldLabel.Visible=true;
        app.ElevationangleofthelastcameradegreesEditField.Visible=true;
        app.ElevationangleofthelastcameradegreesEditFieldLabel.Visible=true;
      elseif app.AlignmenttypeDropDown.Value==4 % gravity
        app.NosettingsforthisalignmenttypeLabel.Visible=false;
        app.CamerarecordingfrequencyHzEditField.Visible=true;
        app.CamerarecordingfrequencyHzEditFieldLabel.Visible=true;
        app.AzimuthangleofthelastcameradegreesEditField.Visible=false;
        app.AzimuthangleofthelastcameradegreesEditFieldLabel.Visible=false;
        app.ElevationangleofthelastcameradegreesEditField.Visible=false;
        app.ElevationangleofthelastcameradegreesEditFieldLabel.Visible=false;
      else
        app.NosettingsforthisalignmenttypeLabel.Visible=true;
        app.CamerarecordingfrequencyHzEditField.Visible=false;
        app.CamerarecordingfrequencyHzEditFieldLabel.Visible=false;
        app.AzimuthangleofthelastcameradegreesEditField.Visible=false;
        app.AzimuthangleofthelastcameradegreesEditFieldLabel.Visible=false;
        app.ElevationangleofthelastcameradegreesEditField.Visible=false;
        app.ElevationangleofthelastcameradegreesEditFieldLabel.Visible=false;
      end

      if app.AlignmenttypeDropDown.Value==1 % 4 point axis
        app.NosettingsforthisalignmenttypeLabel.Value=...
          {['No settings for this alignment type, load an alignment file', ...
          ' with the pixel coordinates of the origin and a point on the', ...
          ' +X, +Y, and +Z axes in that order.']};
      elseif app.AlignmenttypeDropDown.Value==2 % 3 point axis
        app.NosettingsforthisalignmenttypeLabel.Value=...
          {['No settings for this alignment type, load an alignment file', ...
          ' with the pixel coordinates of the origin and a point on the', ...
          ' +X and +Y axes in that order. The +Z direction will be set', ...
          ' by the right hand rule.']};
      elseif app.AlignmenttypeDropDown.Value==3 % plane
        app.NosettingsforthisalignmenttypeLabel.Value=...
          {['No settings for this alignment type, load an alignment file', ...
          ' containing the pixel coordinates of at least 3 points that', ...
          'define a plane. The +X and +Y axes will define the horizontal ', ...
          'plane; +Z will point toward the majority of calibration points.']};
      elseif app.AlignmenttypeDropDown.Value==6 % known 3D points
        app.NosettingsforthisalignmenttypeLabel.Value=...
          {['No settings for this alignment type, load an alignment file', ...
          ' with the pixel coordinates of at least 3 points with known', ...
          ' 3D locations, provided in the last 3 columns of the file. A', ...
          ' simpleWand project with 3 cameras will have a 3x2+3 = 9 column', ...
          ' alignment file of this type, with at least 3 rows.']};
      else
        app.NosettingsforthisalignmenttypeLabel.Value=...
          {['No settings for this alignment type.']};
      end
    end

    function g = polyFitGravity(app,axisXYZ)
      % Fits a 2nd order polynomial to a the time-series of position of a falling
      % object to get the gravitational acceleration vector.

      gravityFreq=app.CamerarecordingfrequencyHzEditField.Value;

      t=(0:size(axisXYZ,1)-1)';
      idx=find(sum(isnan(axisXYZ),2)==0);
      p1=polyfit(t(idx),axisXYZ(idx,1),2);
      p2=polyfit(t(idx),axisXYZ(idx,2),2);
      p3=polyfit(t(idx),axisXYZ(idx,3),2);
      g1(:,1)=polyval(p1,t);
      g1(:,2)=polyval(p2,t);
      g1(:,3)=polyval(p3,t);
      g=diff(diff(g1)).*gravityFreq.^2;
      g=inanmean(g);

    end

    function uvdd = undistortFast(app,uv,intrinsics)
      % function [uvdd] = undisortFast(app,uv,intrinsics)
      %
      % Adapted from easyWand5 undistort because of huge performance
      % problems with MATLAB undistortPoints
      %
      % function [uvdd] = undistort(app,uv,f,UoVo,nlin,niter)
      %
      % inputs:
      %  uv - array of pixel coordinates, distorted
      %  f - focal length
      %  UoVo - principal point
      %  nlin - nonlinear distortion coefficients
      %  niter - number of undistort iterations, 5 is usually more than good
      %
      % outputs:
      %  uvdd - array of pixel coordinates, undistorted
      %
      % Iteratively applies undistortion coefficients to estimate undistorted
      % pixel coordinates from observation of distorted pixel coordinates

      % parse intrinsics
      f = intrinsics.FocalLength(1);
      UoVo = intrinsics.PrincipalPoint;
      nlin = [intrinsics.RadialDistortion(1:2),...
        intrinsics.TangentialDistortion,intrinsics.RadialDistortion(3)];
      niter = 10; % identical to undistortPoints to ~1e-12

      % create normalized points from pixel coordinates
      uvn = (uv - repmat(UoVo,size(uv,1),1))./f;

      uvnd = uvn;

      % undistort (niter iterations)
      for i=1:niter
        r2=rnorm(uvnd).^2; % square of the radius
        rad = 1 + nlin(1)*r2 + nlin(2)*r2.^2 + nlin(5)*r2.^3; % radial distortion
        tan = [2*nlin(3).*uvnd(:,1).*uvnd(:,2) + nlin(4)*(r2 + 2*uvnd(:,1).^2)];
        tan(:,2) = nlin(3)*(r2 + 2*uvnd(:,2).^2) + 2*nlin(4).*uvnd(:,1).*uvnd(:,2);

        uvnd = (uvn - tan)./repmat(rad,1,2);
      end

      % restore pixel coordinates
      uvdd = uvnd*f + repmat(UoVo,size(uv,1),1);

    end

    function ew6Save = makeSaveBundle(app,dType)
      % ew6Save = makeSaveBundle(app,dType)
      %
      % creates an output struct variable with current data from the
      % simpleWand user interface. If dType==0, then only the data
      % of greatest interest are exported, if dType==1 then the full
      % set of data to save to disk are created.

      ew6Save=[];

      ew6Save.saveTime = datestr(now,'yyyy-mm-dd HH:MM:SS.FFF AM');
      ew6Save.easyWand_version = app.version;

      % copy data in app properties
      ew6Save.data.axisPts = app.axisPts;
      ew6Save.data.bkgPts = app.bkgPts;
      ew6Save.data.wandPts = app.wandPts;
      ew6Save.data.extrinsics = app.extrinsics;
      ew6Save.data.intrinsics = app.intrinsics;
      ew6Save.data.numCams = app.numCams;
      ew6Save.data.wandScore = app.wandScore;
      ew6Save.data.distanceScore = app.distanceScore;
      ew6Save.data.rmse = app.rmse;
      ew6Save.data.dltcoefs = app.dltcoefs;
      ew6Save.data.imageOriginLowerLeft = app.imageOriginLowerLeft;

      if dType==0 % stop here if just making simple data
        return
      end

      % copy data and settings in UI elements
      ew6Save.data.cameraTable.Data = app.cameraTable.Data;
      ew6Save.data.CameraDistanceTable.Data = app.CameraDistanceTable.Data;
      ew6Save.data.ShowpointsfromDropDown.Items = app.ShowpointsfromDropDown.Items;
      ew6Save.data.ShowpointsfromDropDown.ItemsData = app.ShowpointsfromDropDown.ItemsData;
      ew6Save.data.ShowpointsfromDropDown_2.Items = app.ShowpointsfromDropDown_2.Items;
      ew6Save.data.ShowpointsfromDropDown_2.ItemsData = app.ShowpointsfromDropDown_2.ItemsData;
      ew6Save.data.ShowpointsfromDropDown.Value = app.ShowpointsfromDropDown.Value;
      ew6Save.data.AlignmentfileEditField.Value = app.AlignmentfileEditField.Value;
      ew6Save.data.AlignmenttypeDropDown.Value = app.AlignmenttypeDropDown.Value;
      ew6Save.data.SetscenescalefrommeasuredcameracameradistancesCheckBox.Value ...
        = app.SetscenescalefrommeasuredcameracameradistancesCheckBox.Value;
      ew6Save.data.PairedpointsfileEditField.Value = app.PairedpointsfileEditField.Value;
      ew6Save.data.InterpointdistanceEditField.Value = app.InterpointdistanceEditField.Value;
      ew6Save.data.UnpairedpointsfileEditField.Value = app.UnpairedpointsfileEditField.Value;
      ew6Save.data.CamerarecordingfrequencyHzEditField.Value = ...
        app.CamerarecordingfrequencyHzEditField.Value;
      ew6Save.data.AzimuthangleofthelastcameradegreesEditField.Value = ...
        app.AzimuthangleofthelastcameradegreesEditField.Value;
      ew6Save.data.ElevationangleofthelastcameradegreesEditField.Value = ...
        app.ElevationangleofthelastcameradegreesEditField.Value;

    end

    function [F] = computeFnew(app,ptNorm)

      % [F] = computeFnew(ptNorm)
      %
      % Computes the Fundamental matrix F via SVD (8 point algorithm) with
      % normalization of the inputs as recommended by Hartley

      % Set average [X,Y] to the center
      mean1=nanmean(ptNorm(:,1:2));
      mean2=nanmean(ptNorm(:,3:4));

      ptNorm(:,1:2)=ptNorm(:,1:2)-repmat(mean1,size(ptNorm,1),1);
      ptNorm(:,3:4)=ptNorm(:,3:4)-repmat(mean2,size(ptNorm,1),1);

      % Scale the average [X,Y] distance to 2^0.5
      scale1 = 2^0.5 ./ nanmean(rnorm(ptNorm(:,1:2)));
      scale2 = 2^0.5 ./ nanmean(rnorm(ptNorm(:,3:4)));

      ptNorm(:,1:2)=ptNorm(:,1:2).*scale1;
      ptNorm(:,3:4)=ptNorm(:,3:4).*scale2;

      % get initial solution
      %
      % construct matrix A
      A(:,1)=ptNorm(:,3).*ptNorm(:,1);
      A(:,2)=ptNorm(:,3).*ptNorm(:,2);
      A(:,3)=ptNorm(:,3);
      A(:,4)=ptNorm(:,4).*ptNorm(:,1);
      A(:,5)=ptNorm(:,4).*ptNorm(:,2);
      A(:,6)=ptNorm(:,4);
      A(:,7)=ptNorm(:,1);
      A(:,8)=ptNorm(:,2);
      A(:,9)=1;

      [~,~,V] = svd(A);
      F=V(:,end);

      % Next step is given as Let F = UDVt be the SVD of F, where D is a diagonal
      % matrix D=diag(r,s,t) satisfying r> s> t.  Then F'=U*diag(r,s,0)*Vt minimizes
      % the Frobenius norm of F-F' [8 point algorithm]

      [U,D,V] = svd(reshape(F,3,3));
      F=U*diag([D(1,1) D(2,2) 0])*V';

      % Re-scale to get rid of the normalizations
      S1=[scale1,0,-scale1*mean1(1); ...
        0, scale1, -scale1*mean1(2); ...
        0,0,1];

      S2=[scale2,0,-scale2*mean2(1); ...
        0, scale2, -scale2*mean2(2); ...
        0,0,1];

      F = S1'*F*S2;

      F = F./F(3,3);

    end


    function [] = clearPlot(app)
      cla(app.plotAxes);  % Clear figure axes
    end

    function [] = updateAxisPointsGrid(app)
      % updates the axis points UI table
      cam=app.ShowalignmentpointsfromDropDown.Value;
      try
      app.alignmentPointsTable.Data=table(app.axisPts.ID,app.axisPts.data(:,cam*2-1),...
        app.axisPts.data(:,cam*2),app.axisPts.xyz(:,1),app.axisPts.xyz(:,2),...
        app.axisPts.xyz(:,3),app.axisPts.rmse);
      catch
      end

    end
  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)

      % set alignment menu options - start with azimuth/elevation because
      % that one produces sensible output with no further inputs
      app.AlignmenttypeDropDown.ItemsData=1:6;
      app.AlignmenttypeDropDown.Value=5;

      app.updateAlignmentOptionVisibility();

      % display welcome text
      app.msg={};
      app.msg{1}='Welcome to easyWand6';
      app.msg{end+1}=sprintf('Version %d.%d.%d',app.version(1),app.version(2),app.version(3));
      app.msgTxtArea.Value=app.msg;
      app.msgTxtArea.scroll('bottom');
    end

    % Menu selected function: NewprojectMenu
    function NewprojectMenuSelected(app, event)

      answer = inputdlg('How many cameras does your project have?','How many cameras?');
      if isempty(answer) % user cancel
        app.msg{numel(app.msg)+1}=['New project canceled.'];
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
        return
      end
      try
        ncams=round(str2num(answer{1}));
        if ncams<2 || isempty(ncams)
          app.msg{numel(app.msg)+1}='easyWand6 projects need to have at least 2 cameras';
          app.msgTxtArea.Value=app.msg;
          app.msgTxtArea.scroll('bottom');
          return
        end

        app.msg{numel(app.msg)+1}=['Creating a ',num2str(ncams),' camera project.'];
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
        app.numCams=ncams;
      catch % bad inputs
        app.msg{numel(app.msg)+1}=['New project: bad input, please try again.'];
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
        return
      end

      % initialize the data arrays
      app.bkgPts.data=zeros(0,ncams*2);
      app.bkgPts.ID=zeros(0,1);
      app.bkgPts.outlier=zeros(0,1);
      app.bkgPts.xyz=zeros(0,3);
      app.bkgPts.rmse=zeros(0,1);

      app.wandPts.data=zeros(0,ncams*4);
      app.wandPts.ID=zeros(0,1);
      app.wandPts.outlier=zeros(0,2);
      app.wandPts.xyz=zeros(0,6);
      app.wandPts.rmse=zeros(0,2);
      app.wandPts.wandLength=zeros(0,1);

      app.axisPts.data=zeros(0,ncams*2);
      app.axisPts.xyz=zeros(0,3);
      app.axisPts.ID=zeros(0,1);
      app.axisPts.rmse=zeros(0,1);

      % populate camera intrinsics table
      num=(1:ncams)';
      primary=true(size(num));
      Uo=num*NaN;
      Vo=num*NaN;
      f=num*NaN;
      d2=num*0;
      d4=num*0;
      d6=num*0;
      app.cameraTable.Data=table(num,primary,Uo,Vo,f,d2,d4,d6);

      % populate camera distance table
      camDist=[];
      for i=1:ncams-1
        for j=i+1:ncams
          camDist=[camDist;[i,j]];
        end
      end
      camDist(:,3)=NaN;
      app.CameraDistanceTable.Data=table(camDist(:,1),camDist(:,2),camDist(:,3));

      % turn on the UI
      app.NewprojectMenu.Enable=false;
      app.LoadasavedprojectMenu.Enable=false;
      app.SavethisprojectMenu.Enable=true;
      app.ExportcameraprofileMenu.Enable=true;
      app.DataMenu.Enable=true;
      app.ExporttoworkspaceMenu.Enable=true;
      if ncams>2
        app.IntrinsicparamatersDropDown.Enable=true;
      end

      % setup the camera menu in the paired and unpaired points tabs
      camNames={};
      camNums=1:ncams;
      for i=1:ncams
        camNames{i}=['Camera ',num2str(i)];
      end
      app.ShowpointsfromDropDown.Items=camNames;
      app.ShowpointsfromDropDown.ItemsData=camNums;
      app.ShowalignmentpointsfromDropDown.Items=camNames;
      app.ShowalignmentpointsfromDropDown.ItemsData=camNums;

      camNames={};
      camOpts=1:ncams*2;
      for i=1:ncams*2
        camNames{i}=['Cam ',num2str(ceil(i/2)),' pt ',num2str(abs(mod(i,2)-1)+1)];
      end
      app.ShowpointsfromDropDown_2.Items=camNames;
      app.ShowpointsfromDropDown_2.ItemsData=camOpts;


    end

    % Menu selected function: LoadpairedpointsMenu
    function LoadpairedpointsMenuSelected(app, event)
      [filename, pathname] = uigetfile({'*.csv', 'Comma separated array';'*.tsv','Sparse array file'});
      if filename==0
        % cancel
        return
      end
      try
        if strcmpi(filename(end-2:end),'csv')
          foo=table2array(readtable([pathname,filesep,filename]));
          if isstruct(foo)
            foo=foo.data;
          end
          ID=find(sum(~isnan(foo),2)>0);
          foo=foo(ID,:);
        elseif strcmpi(filename(end-2:end),'tsv')
          foo=sparseRead([pathname,filesep,filename]);
          ID=find(sum(foo~=0,2)>0);
          foo=foo(ID,:);
          foo=sp2full(foo);
        end

        % input sanity check
        if size(foo,2)~=size(app.cameraTable.Data,1)*4
          app.msg{end+1}='This wand points file is the wrong size - it should have 4 columns for each camera.';
          app.msgTxtArea.Value=app.msg;
          app.msgTxtArea.scroll('bottom');
          return;
        end

        app.wandPts.filename=filename;
        app.wandPts.pathname=pathname;
        app.wandPts.data=foo;
        app.wandPts.xyz=nan(size(foo,1),6);
        app.wandPts.rmse=nan(size(foo,1),2);
        app.wandPts.outlier=false(size(foo,1),2);
        app.wandPts.wandLength=nan(size(foo,1),1);
        app.wandPts.ID=ID;
        app.PairedpointsfileEditField.Value=[app.wandPts.pathname,app.wandPts.filename];
        app.OperationsMenu.Enable=true;

        % update UI table & raise that tab
        updatePairedPointsGrid(app)
        app.msg{end+1}=['Loaded paired points file ',pathname,filename];
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
        app.TabGroup.SelectedTab=app.TabGroup.Children(1);

      catch ME
        disp(ME)
        app.msg{end+1}='Could not parse the paired points file';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
      end

    end

    % Menu selected function: LoadunpairedpointsMenu
    function LoadunpairedpointsMenuSelected(app, event)
      [filename, pathname] = uigetfile(...
        {'*.csv;*.tsv','Text data files (*.csv,*.tsv)';...
        '*.csv','Comma separated array';...
        '*.tsv','Sparse array file'});
      if filename==0
        % cancel
        return
      end
      try
        if strcmpi(filename(end-2:end),'csv')
          %foo=importdata([pathname,filesep,filename]);
          foo=table2array(readtable([pathname,filesep,filename]));
          if isstruct(foo)
            foo=foo.data;
          end
          ID=find(sum(~isnan(foo),2)>0);
          foo=foo(ID,:);
        elseif strcmpi(filename(end-2:end),'tsv')
          foo=sparseRead([pathname,filesep,filename]);
          ID=find(sum(foo~=0,2)>0);
          foo=foo(ID,:);
          foo=sp2full(foo);
        end

        % input sanity check
        if size(foo,2)~=size(app.cameraTable.Data,1)*2
          app.msg{end+1}='This unpaired points file is the wrong size - it should have 2 columns for each camera.';
          app.msgTxtArea.Value=app.msg;
          app.msgTxtArea.scroll('bottom');
          return
        end

        % setup data structure
        app.bkgPts.filename=filename;
        app.bkgPts.pathname=pathname;
        app.bkgPts.data=foo;
        app.bkgPts.ID=ID;
        app.bkgPts.xyz=nan(size(foo,1),3);
        app.bkgPts.rmse=nan(size(foo,1),1);
        app.bkgPts.outlier=false(size(foo,1),1);
        app.UnpairedpointsfileEditField.Value=[app.bkgPts.pathname,app.bkgPts.filename];
        app.OperationsMenu.Enable=true;

        % update UI table
        updateUnpairedPointsGrid(app)

        % bring tab to foreground
        app.TabGroup.SelectedTab=app.TabGroup.Children(2);

        app.msg{end+1}=sprintf('Loaded unpaired points file %s',filename);
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');

      catch ME
        disp(ME)
        app.msg{end+1}='Could not parse the unpaired points file.';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
      end

    end

    % Menu selected function: LoadalignmentfileMenu
    function LoadalignmentfileMenuSelected(app, event)
      [filename, pathname] = uigetfile({'*.csv', 'Comma separated array';'*.tsv','Sparse array file'});
      if filename==0
        % cancel
        return
      end
      try
        if strcmpi(filename(end-2:end),'csv')
          %foo=importdata([pathname,filesep,filename]);
          foo=table2array(readtable([pathname,filesep,filename]));
          if isstruct(foo)
            foo=foo.data;
          end
          ID=find(sum(~isnan(foo),2)>0);
          foo=foo(ID,:);
        elseif strcmpi(filename(end-2:end),'tsv')
          foo=sparseRead([pathname,filesep,filename]);
          ID=find(sum(foo~=0,2)>0);
          foo=foo(ID,:);
          foo=sp2full(foo);
        end

        app.axisPts.filename=filename;
        app.axisPts.pathname=pathname;
        app.axisPts.data=foo;
        app.axisPts.ID=ID;
        app.axisPts.rmse=nan(size(foo,1),1);
        app.AlignmentfileEditField.Value=[app.axisPts.pathname,app.axisPts.filename];

        % bring tab to foreground
        app.TabGroup.SelectedTab=app.TabGroup.Children(3);

        app.msg{end+1}=sprintf('Loaded alignment or axis points file %s',filename);
        app.msg{end+1}='Be sure to set the alignment file type, e.g. gravity, 4-point axis, etc.';
        app.msg{end+1}='and set any needed options such as the recording frequency.';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
        drawnow

        % turn the menu red until it's clicked next
        app.AlignmenttypeDropDown.BackgroundColor=[1,.33,.33];

      catch ME
        disp(ME)
        app.msg{end+1}='Could not parse the alignment points file';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
      end


    end

    % Menu selected function: LoadacameraprofileMenu
    function LoadacameraprofileMenuSelected(app, event)
      [filename, pathname] = uigetfile({'*.txt', 'easyWand, simpleWand or Argus camera profile'});
      if filename==0
        % cancel
        return
      end
      try
        foo=importdata([pathname,filename]);
        if size(foo,1)~=size(app.cameraTable.Data,1)
          app.msg{end+1}='The number of cameras in the profile does not match the project';
          app.msgTxtArea.Value=app.msg;
          app.msgTxtArea.scroll('bottom');
          return
        end
        for i=1:size(foo,1)
          j=foo(i,1); % get the camera ID
          app.cameraTable.Data.f(j)=foo(i,2);
          app.cameraTable.Data.Uo(j)=foo(i,5);
          app.cameraTable.Data.Vo(j)=foo(i,6);
          app.cameraTable.Data.d2(j)=foo(i,8);
          app.cameraTable.Data.d4(j)=foo(i,9);
          app.cameraTable.Data.d6(j)=foo(i,12);
        end
        app.msg{end+1}='Camera profile loaded';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
      catch ME
        disp(ME)
        app.msg{end+1}='Error parsing camera profile';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
      end
    end

    % Menu selected function: computecalibrationMenu
    function computecalibrationMenuSelected(app, event)
      % setup
      app.easyWandUIFigure.Pointer='watch';
      app.cameraTableDataBak=app.cameraTable.Data;
      app.restoreoriginalcameraparametersMenu.Enable=true;
      wandPtsOffset=2*app.numCams;
      app.msg{end+1}='_';
      app.msg{end+1}='Starting calibration...';
      app.msgTxtArea.Value=app.msg;
      app.msgTxtArea.scroll('bottom');
      clearPlot(app)
      drawnow


      %% PRIMARY CAMERAS %%

      % check for camera data
      if sum(sum(isnan(table2array(app.cameraTable.Data(:,3:end)))))>0
        beep
        app.msg{end+1}='You need to have a valid specification for all cameras before calibrating.';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');

        % flash the colors in the camera table
        bb=app.cameraTable.BackgroundColor;
        app.cameraTable.BackgroundColor=[1,0,0;0.8,0,0];
        pause(0.25)
        app.cameraTable.BackgroundColor=bb;
        app.easyWandUIFigure.Pointer='arrow';
        return
      end

      % check camera count
      pcams=find(app.cameraTable.Data.primary==true);
      if numel(pcams)<2
        app.msg{end+1}='You need at least 2 primary cameras for a calibration';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
        app.easyWandUIFigure.Pointer='arrow';
        return
      end
      columnID=sort([pcams'*2-1,pcams'*2]); % column indices for the primary cameras in the uv data arrays

      % setup camera intrinsics
      for i=1:numel(pcams)
        intrinsics(i).FocalLength=app.cameraTable.Data.f(pcams(i));
        intrinsics(i).PrincipalPoint=[app.cameraTable.Data.Uo(pcams(i)),app.cameraTable.Data.Vo(pcams(i))];
        intrinsics(i).RadialDistortion=table2array(app.cameraTable.Data(pcams(i),6:8));
        intrinsics(i).TangentialDistortion=[0,0]; % assumen no tangential distortion [0] or [0,0]??
        intrinsics(i).Skew=0; % assume now skew
        intrinsics(i).imageSize=intrinsics(i).PrincipalPoint*2; % assume centered PP
      end

      % gather all points
      % paired
      try
        % all wand points, regardless of whether or not they're non-NaN
        wxy=[app.wandPts.data(:,[columnID,columnID+wandPtsOffset])];

        % mask outliers
        wxy(app.wandPts.outlier(:,1),1:numel(pcams)*2)=NaN;
        wxy(app.wandPts.outlier(:,2),numel(pcams)*2+1:end)=NaN;

      catch
        wxy=zeros(0,numel(pcams)*2);
      end
      % unpaired
      try
        % all background points, regardless of whether or not they're non-NaN
        bxy=[app.bkgPts.data(:,columnID)];

        % mask outliers
        bxy(app.bkgPts.outlier,:)=NaN;
      catch
        bxy=[];
      end
      % axis
      try
        if size(app.axisPts.data,2)~=3 % indicative of a camera-azimuth-elevation alignment
          axy=[app.axisPts.data(:,columnID)];
        else
          axy=[];
        end
      catch
        axy=[];
      end

      allxy=[wxy(:,1:numel(pcams)*2);wxy(:,numel(pcams)*2+1:end);bxy;axy];

      % undistort the camera points if necessary
      allxyRaw=allxy; % store for SBA
      for i=1:numel(pcams)
        % Mathworks code - very very slow for large arrays
        % allxy(isfinite(allxy(:,i*2)),i*2-1:i*2)=undistortPoints(allxy(isfinite(allxy(:,i*2)),i*2-1:i*2),intrinsics(i));

        % Hedrick code - much faster :)
        allxy(isfinite(allxy(:,i*2)),i*2-1:i*2)=undistortFast(app,allxy(isfinite(allxy(:,i*2)),i*2-1:i*2),intrinsics(i));

      end


      % estimate fundamental matrix, pose & orientation for all but last camera
      for i=1:numel(pcams)-1
        % uv for fundamental matrix est.
        efmxy=[allxy(:,end-1:end),allxy(:,i*2-1:i*2)]; % last camera + ith camera
        efmidx=find(sum(isfinite(efmxy),2)<4);
        efmxy(efmidx,:)=[];

        efmxyNorm=[];
        efmxyNorm(:,1:2)=(efmxy(:,1:2)-intrinsics(end).PrincipalPoint)./intrinsics(end).FocalLength(1);
        efmxyNorm(:,3:4)=(efmxy(:,3:4)-intrinsics(i).PrincipalPoint)./intrinsics(i).FocalLength(1);


        % do the actual estimation
        try
          if exist('estimateFundamentalMatrix','file')==2
            if size(efmxy,1)>1000
              F = estimateFundamentalMatrix(efmxy(:,1:2),efmxy(:,3:4),'Method', 'Norm8Point');
            else
              F = estimateFundamentalMatrix(efmxy(:,1:2),efmxy(:,3:4),'Method', 'Norm8Point');
            end
          else
            F = computeFnew(app,[efmxy(:,3:4),efmxy(:,1:2)]); % Ty's implementation
          end

        catch ME
          app.msg{end+1}=['Error getting preliminary calibration for camera ',num2str(pcams(i))];
          app.msg{end+1}=['  Add more data for this camera or make it secondary.'];
          app.msg{end+1}=['  Aborting calibration.'];
          app.msgTxtArea.Value=app.msg;
          app.msgTxtArea.scroll('bottom');
          app.easyWandUIFigure.Pointer='arrow';
          return
        end

        % get Essential matrix from F & camera matrix
        K1=[intrinsics(end).FocalLength(1),0,0; ...] % k1 is the base cameras
          0,intrinsics(end).FocalLength(1),0;...
          intrinsics(end).PrincipalPoint(1),intrinsics(end).PrincipalPoint(2),1];

        K2=[intrinsics(i).FocalLength(1),0,0; ...] % k2 is the other camera
          0,intrinsics(i).FocalLength(1),0;...
          intrinsics(i).PrincipalPoint(1),intrinsics(i).PrincipalPoint(2),1];

        E = K2 * F * K1'; % best?
        % efmxy(:,1:2) is the base camera, efmxy(:,3:4) is the varying camera
        [R(:,:,i),tv(1,:,i)] = twoCamCal_v4(E,K2,K1,efmxy(:,1:2),efmxy(:,3:4));

      end

      % add on the base (last) camera
      R(:,:,end+1)=eye(3);
      tv(1,:,end+1)=[0,0,0]';
      ViewId=uint32(1:numel(pcams)); % camera index

      % setup tracks
      for i=1:size(allxy,1)
        d=reshape(allxy(i,:),2,size(allxy,2)/2)';
        vID=ViewId(sum(isfinite(d),2)==2);
        if numel(vID)>1 %if isempty(vID)==false
          %pointTracks(i)=pointTrack(ViewId,reshape(allxy(i,:),2,size(allxy,2)/2)');
          pointTracks(i)=pointTrack(vID,d(vID,:));
        end
      end

      % setup camera matrices
      Ks=[];
      for i=1:numel(pcams)
        Ks{i}=[intrinsics(i).FocalLength(1),0,0; ...
          0,intrinsics(i).FocalLength(1),0;...
          intrinsics(i).PrincipalPoint(1),intrinsics(i).PrincipalPoint(2),1]';
      end
      Rs=[];
      ts=[];
      for i=1:numel(pcams)
        Rs{i}=R(:,:,i);
        ts{i}=tv(:,:,i)';
      end

      [xyzPoints, reprojErrors] = triangulate_v4(Ks, Rs, ts, allxy);
      %disp('triangule - first pass')
      %disp(xyzPoints(1:4,:))

      % SBA bundle adjustment
      % collect camera intrinsics
      sbaIntrinsics=[];
      sbaDistortion=[];
      for i=1:numel(pcams)
        sbaIntrinsics(i,:)=[intrinsics(i).FocalLength,intrinsics(i).PrincipalPoint,1,0];
        sbaDistortion(i,:)=[intrinsics(i).RadialDistortion(1:2),...
          intrinsics(i).TangentialDistortion,intrinsics(i).RadialDistortion(3)];
      end

      camPos=zeros(numel(pcams),3);
      camR=zeros(numel(pcams),4);
      for i=1:numel(pcams)-1
        camR(i,:)=RMtoQ(R(:,:,i)); % convert rotation matrix to quaternion
        camPos(i,:)=tv(:,:,i);
      end
      camR(numel(pcams),:)=[1,0,0,0];
      distortionFlag=app.LensdistortionDropDown.Value;
      intrinsicsFlag=app.IntrinsicparamatersDropDown.Value;

      % do easySBA
      idx=find(~isnan(xyzPoints(:,1)));
      if numel(idx)<32
        app.msg{end+1}=['WARNING - there may be too few datapoints for SBA to operate correctly!'];
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
      end

      [xyzRefinedPoints(idx,:), camPosout, camRout, intrinsicsout, distortionout] = ...
        easySBA(allxyRaw(idx,:), xyzPoints(idx,:), camPos, camR, sbaIntrinsics, intrinsicsFlag, ...
        sbaDistortion, distortionFlag);
      xyzRefinedPoints(xyzRefinedPoints==0)=NaN;
      idx2=find(~isnan(xyzRefinedPoints(:,1)));

      % check for SBA failure
      if numel(idx)~=numel(idx2) | isempty(idx2)
        app.msg{end+1}=['ERROR - SBA optimization failed!!'];
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
        xyzRefinedPoints=xyzPoints;
        camRout=camR;
        camPosout=camPos;
        app.easyWandUIFigure.Pointer='arrow';
        return
      end

      %disp('triangulation - SBA pass')
      %disp(xyzRefinedPoints(1:4,:))

      % update camera intrinsics and distortion, need to make sure we
      % do the underlying table AND the data in this function
      for i=1:numel(pcams)
        % interface data table
        app.cameraTable.Data.f(pcams(i))=intrinsicsout(i,1);
        app.cameraTable.Data.Uo(pcams(i))=intrinsicsout(i,2);
        app.cameraTable.Data.Vo(pcams(i))=intrinsicsout(i,3);

        % data in this function
        intrinsics(i).FocalLength=[intrinsicsout(i,1),intrinsicsout(i,1)];
        intrinsics(i).PrincipalPoint=[app.cameraTable.Data.Uo(pcams(i)),app.cameraTable.Data.Vo(pcams(i))];

        % distortion in this function
        intrinsics(i).RadialDistortion(1:2)=distortionout(i,1:2);
        intrinsics(i).RadialDistortion(3)=distortionout(i,5);
        intrinsics(i).TangentialDistortion=distortionout(i,3:4);

        % distortionin the interface data table
        app.cameraTable.Data.d2(pcams(i))=distortionout(i,1);
        app.cameraTable.Data.d4(pcams(i))=distortionout(i,2);
        app.cameraTable.Data.d6(pcams(i))=distortionout(i,5);
      end

      % create a refinedPoses output table for compatibility with the
      % way the MATLAB bundleAdjustment() works
      rfPosesStruct=[];
      for i=1:numel(pcams)
        rfPosesStruct(i).ViewId=pcams(i);
        rfPosesStruct(i).Orientation={QtoRM(camRout(i,:))};
        rfPosesStruct(i).Location={camPosout(i,:)}; % *R for matlab did not help
      end
      refinedPoses=struct2table(rfPosesStruct);

      % re-do distortion if distortion coefs or intrinsics have changed
      if distortionFlag~=5 || intrinsicsFlag~=5
        for i=1:numel(pcams)
          % Hedrick code - much faster :)
          allxy(isfinite(allxy(:,i*2)),i*2-1:i*2)=undistortFast(app,allxyRaw(isfinite(allxy(:,i*2)),i*2-1:i*2),intrinsics(i));
        end
      end

      % test triangulation - should match xyzRefinedPoints (which it
      % does) and get the refinedErrors
      for i=1:numel(pcams)
        Rs{i}=refinedPoses.Orientation{i};
        ts{i}=refinedPoses.Location{i}';
        Ks{i}=[intrinsics(i).FocalLength(1),0,0; ...
          0,intrinsics(i).FocalLength(1),0;...
          intrinsics(i).PrincipalPoint(1),intrinsics(i).PrincipalPoint(2),1]';
      end
      [xyzPoints2, reprojErrors2] = triangulate_v4(Ks, Rs, ts, allxy);
      %disp('triangule - check pass')
      %disp(xyzPoints2(1:4,:))
      refinedErrors=reprojErrors2;

      % pad if necessary
      xyzRefinedPoints(end+1:size(allxy,1),:)=NaN;
      refinedErrors(end+1:size(allxy,1),:)=NaN;

      % set 0 values back to NaN
      xyzRefinedPoints(xyzRefinedPoints==0)=NaN;
      refinedErrors(refinedErrors==0)=NaN;

      % display results
      app.msg{end+1}=['SBA bundle adjustment complete, see MATLAB console for details.'];
      app.msgTxtArea.Value=app.msg;
      app.msgTxtArea.scroll('bottom');


      %% END PRIMARY CAMERA CALIBRATION %%

      %% SECONDARY CAMERA CALIBRATION %%

      % estimate secondary camera extrinsics from 3D points determined in
      % the primary calibration

      % setup camera intrinsics
      scams=find(app.cameraTable.Data.primary==false);
      if numel(scams)>0
        app.msg{end+1}='Beginning secondary camera calibration';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
        drawnow

        columnID1=sort([scams'*2-1,scams'*2]); % column indices for the secondary cameras in the uv data arrays

        % % build intrinsics array for the secondary cameras
        % for i=1:numel(scams)
        %     sintrinsics(i)=cameraIntrinsics(app.cameraTable.Data.f(scams(i)),[app.cameraTable.Data.Uo(scams(i)),app.cameraTable.Data.Vo(scams(i))],...
        %         [app.cameraTable.Data.Vo(scams(i)),...
        %         app.cameraTable.Data.Uo(scams(i))]*2,...
        %         'RadialDistortion',table2array(app.cameraTable.Data(scams(i),6:8)));
        % end
        % setup camera intrinsics
        for i=1:numel(scams)
          sintrinsics(i).FocalLength=app.cameraTable.Data.f(scams(i));
          sintrinsics(i).PrincipalPoint=[app.cameraTable.Data.Uo(scams(i)),app.cameraTable.Data.Vo(scams(i))];
          sintrinsics(i).RadialDistortion=table2array(app.cameraTable.Data(scams(i),6:8));
          sintrinsics(i).TangentialDistortion=[0,0]; % assumen no tangential distortion [0] or [0,0]??
          sintrinsics(i).Skew=0; % assume now skew
          sintrinsics(i).imageSize=sintrinsics(i).PrincipalPoint*2; % assume centered PP
        end

        for i=1:numel(scams)
          columnID1a=columnID1(1,i*2-1:i*2);
          % gather 2D and 3D points
          % paired
          try
            % all wand points, regardless of whether or not they're non-NaN
            wxy=[app.wandPts.data(:,[columnID1a,columnID1a+wandPtsOffset])];
            wxyz=app.wandPts.xyz;
          catch
            wxy=[];
            wxyz=[];
          end
          % unpaired
          try
            bxy=[app.bkgPts.data(:,columnID1a)];
            bxyz=app.bkgPts.xyz;
          catch
            bxy=[];
            bxyz=[];
          end
          % axis
          try
            axy=[app.axisPts.data(:,columnID1a)];
            axyz=app.axisPts.xyz;
          catch
            axy=[];
            axyz=[];
          end

          allxy=[wxy(:,1:size(wxy,2)/2);wxy(:,size(wxy,2)/2+1:end);bxy;axy];

          % undistort the camera points if necessary
          allxyRaw=allxy; % keep the distorted points for SBA
          %allxy(isfinite(allxy(:,2)),1:2)=undistortPoints(allxy(isfinite(allxy(:,2)),1:2),sintrinsics(i));
          allxy(isfinite(allxy(:,2)),1:2)=undistortFast(app,allxy(isfinite(allxy(:,2)),1:2),sintrinsics(i));

          % xyz from the refined points created by bundle adjustment of
          % primary cameras
          allxyz=xyzRefinedPoints;

          % estimate pose & orientation for secondary cameras using PnP
          % algorithm. imagePoints = m x 2 array, worldPoints is m x 3 array,
          % cameraParams is a cameraParameters or cameraIntrinsics object
          try
            goodData=find(allxy(:,1)~=0 & isnan(allxy(:,1))==false & allxyz(:,1)~=0 & isnan(allxyz(:,1))==false);
            xyN=(allxy(goodData,:)-sintrinsics(i).PrincipalPoint)./sintrinsics(i).FocalLength(1);
            [sworldOrientation{i},sworldLocation{i}]=EPnP(allxyz(goodData,:)',xyN');


            % [sworldOrientation{i},sworldLocation{i}] = estimateWorldCameraPose(allxy(goodData,:),allxyz(goodData,:),...
            %     sintrinsics(i),'MaxNumTrials',1000,'Confidence',1,'MaxReprojectionError',10);
          catch ME
            app.msg{end+1}=ME.message;
            app.msg{end+1}='Error adding secondary camera, calibration abort.';
            app.msgTxtArea.Value=app.msg;
            app.msgTxtArea.scroll('bottom');
            app.easyWandUIFigure.Pointer='arrow';
            return
          end


        end

        % Now with the secondary cameras extrinsics estimated, re-optimize
        % the whole system

        % primary + secondary calibration
        allcams=[scams;pcams]; % secondary cameras are first
        columnID1=sort([scams'*2-1,scams'*2]);
        columnID2=sort([pcams'*2-1,pcams'*2]);
        columnID=[columnID1,columnID2];

        % gather points
        % paired
        try
          % all wand points, regardless of whether or not they're non-NaN
          wxy=[app.wandPts.data(:,[columnID,columnID+wandPtsOffset])];

          % mask outliers
          wxy(app.wandPts.outlier(:,1),1:numel(allcams)*2)=NaN;
          wxy(app.wandPts.outlier(:,2),numel(allcams)*2+1:end)=NaN;
        catch
          wxy=[];
        end
        % unpaired
        try
          % all background points, regardless of whether or not they're non-NaN
          bxy=app.bkgPts.data(:,columnID);

          % mask outliers
          bxy(app.bkgPts.outlier,:)=NaN;
        catch
          bxy=[];
        end
        % axis
        try
          axy=[app.axisPts.data(:,columnID)];
        catch
          axy=[];
        end

        allxy=[wxy(:,1:size(wxy,2)/2);wxy(:,size(wxy,2)/2+1:end);bxy;axy];

        % % setup intrinsics for all cameras
        % for i=1:numel(allcams)
        %     intrinsics(i)=cameraIntrinsics(app.cameraTable.Data.f(allcams(i)),...
        %         [app.cameraTable.Data.Uo(allcams(i)),app.cameraTable.Data.Vo(allcams(i))],...
        %         [app.cameraTable.Data.Vo(allcams(i)),app.cameraTable.Data.Uo(allcams(i))]*2,...
        %         'RadialDistortion',table2array(app.cameraTable.Data(allcams(i),6:8)));
        % end

        % setup intrinsics for all cameras
        intrinsics=[];
        for i=1:numel(allcams)
          intrinsics(i).FocalLength=app.cameraTable.Data.f(allcams(i));
          intrinsics(i).PrincipalPoint=[app.cameraTable.Data.Uo(allcams(i)),app.cameraTable.Data.Vo(allcams(i))];
          intrinsics(i).RadialDistortion=table2array(app.cameraTable.Data(allcams(i),6:8));
          intrinsics(i).TangentialDistortion=[0,0]; % assume no tangential distortion [0] or [0,0]??
          intrinsics(i).Skew=0; % assume now skew
          intrinsics(i).imageSize=intrinsics(i).PrincipalPoint*2; % assume centered PP
        end

        % undistort the camera points if necessary
        allxyRaw=allxy;
        for i=1:numel(allcams)
          % undistort the camera points if necessary
          %allxy(isfinite(allxy(:,i*2)),i*2-1:i*2)=undistortPoints(allxy(isfinite(allxy(:,i*2)),i*2-1:i*2),intrinsics(i));
          allxy(isfinite(allxy(:,i*2)),i*2-1:i*2)=undistortFast(app,allxy(isfinite(allxy(:,i*2)),i*2-1:i*2),intrinsics(i));
        end


        % setup poses for all cameras
        % make poses table
        %         R{end+1}=eye(3);
        %       tv{end+1}=[0,0,0];
        R={};
        tv={};
        cnt=1;
        % first do secondary cameras
        for i=1:numel(sworldLocation)
          R{cnt}=sworldOrientation{i};
          tv{cnt}=sworldLocation{i};
          cnt=cnt+1;
        end
        % now primary cameras
        for i=1:size(refinedPoses,1)
          R{cnt}=refinedPoses.Orientation{i};
          tv{cnt}=refinedPoses.Location{i}';
          cnt=cnt+1;
        end
        Ks=[];
        for i=1:numel(allcams)
          Ks{i}=[intrinsics(i).FocalLength,0,0; ...
            0,intrinsics(i).FocalLength,0;...
            intrinsics(i).PrincipalPoint(1),intrinsics(i).PrincipalPoint(2),1]';
        end

        [xyzPoints, reprojErrors] = triangulate_v4(Ks, R, tv, allxy);
        % disp('triangulate (2nd) - first pass')
        % disp(xyzPoints(1:4,:))

        % SBA bundle adjustment
        % collect camera intrinsics
        sbaIntrinsics=[];
        sbaDistortion=[];
        for i=1:numel(allcams)
          sbaIntrinsics(i,:)=[intrinsics(i).FocalLength,intrinsics(i).PrincipalPoint,1,0];
          sbaDistortion(i,:)=[intrinsics(i).RadialDistortion(1:2),...
            intrinsics(i).TangentialDistortion,intrinsics(i).RadialDistortion(3)];
        end

        camPos=zeros(numel(allcams),3);
        camR=zeros(numel(allcams),4);
        for i=1:numel(allcams)-1
          camR(i,:)=RMtoQ(R{i}); % convert rotation matrix to quaternion
          camPos(i,:)=tv{i}';
        end
        camR(numel(allcams),:)=[1,0,0,0];
        distortionFlag=app.LensdistortionDropDown.Value;
        intrinsicsFlag=app.IntrinsicparamatersDropDown.Value;

        % do easySBA
        idx=find(~isnan(xyzPoints(:,1)));
        if numel(idx)<32
          app.msg{end+1}=['WARNING - there may be too few datapoints for SBA to operate correctly!'];
          app.msgTxtArea.Value=app.msg;
          app.msgTxtArea.scroll('bottom');
          drawnow
        end

        [xyzRefinedPoints(idx,:), camPosout, camRout, intrinsicsout, distortionout] = ...
          easySBA(allxyRaw(idx,:), xyzPoints(idx,:), camPos, camR, sbaIntrinsics, intrinsicsFlag, ...
          sbaDistortion, distortionFlag);
        xyzRefinedPoints(xyzRefinedPoints==0)=NaN;
        refinedErrors=xyzRefinedPoints(:,1)*NaN; % no errors from SBA?
        idx2=find(~isnan(xyzRefinedPoints(:,1)));

        % check for SBA failure
        if numel(idx)~=numel(idx2) | isempty(idx2)
          app.msg{end+1}=['ERROR - SBA optimization failed!!'];
          app.msgTxtArea.Value=app.msg;
          app.msgTxtArea.scroll('bottom');
          xyzRefinedPoints=xyzPoints;
          camRout=camR;
          camPosout=camPos;
        end

        % disp('triangulation - SBA pass')
        % disp(xyzRefinedPoints(1:4,:))

        % update camera intrinsics and distortion, need to make sure we
        % do the underlying table AND the data in this function
        for i=1:numel(allcams)
          % interface data table
          app.cameraTable.Data.f(allcams(i))=intrinsicsout(i,1);
          app.cameraTable.Data.Uo(allcams(i))=intrinsicsout(i,2);
          app.cameraTable.Data.Vo(allcams(i))=intrinsicsout(i,3);

          % data in this function
          intrinsics(i).FocalLength=[intrinsicsout(i,1),intrinsicsout(i,1)];
          intrinsics(i).PrincipalPoint=[app.cameraTable.Data.Uo(allcams(i)),app.cameraTable.Data.Vo(allcams(i))];

          % distortion in this function
          intrinsics(i).RadialDistortion(1:2)=distortionout(i,1:2);
          intrinsics(i).RadialDistortion(3)=distortionout(i,5);
          intrinsics(i).TangentialDistortion=distortionout(i,3:4);

          % distortionin the interface data table
          app.cameraTable.Data.d2(allcams(i))=distortionout(i,1);
          app.cameraTable.Data.d4(allcams(i))=distortionout(i,2);
          app.cameraTable.Data.d6(allcams(i))=distortionout(i,5);
        end

        % create a refinedPoses output table for compatibility with the
        % way the MATLAB bundleAdjustment() works
        rfPosesStruct=[];
        for i=1:numel(allcams)
          rfPosesStruct(i).ViewId=allcams(i);
          rfPosesStruct(i).Orientation={QtoRM(camRout(i,:))};
          rfPosesStruct(i).Location={camPosout(i,:)}; % *R for matlab did not help
        end
        refinedPoses=struct2table(rfPosesStruct);

        % re-do distortion if distortion coefs or intrinsics have changed
        if distortionFlag~=5 || intrinsicsFlag~=5
          for i=1:numel(allcams)
            % Hedrick code - much faster :)
            allxy(isfinite(allxy(:,i*2)),i*2-1:i*2)=undistortFast(app,allxyRaw(isfinite(allxy(:,i*2)),i*2-1:i*2),intrinsics(i));
          end
        end

        % test triangulation - should match xyzRefinedPoints (which it
        % does) and get the refinedErrors
        for i=1:numel(allcams)
          Rs{i}=refinedPoses.Orientation{i};
          ts{i}=refinedPoses.Location{i}';
          Ks{i}=[intrinsics(i).FocalLength(1),0,0; ...
            0,intrinsics(i).FocalLength(1),0;...
            intrinsics(i).PrincipalPoint(1),intrinsics(i).PrincipalPoint(2),1]';
        end
        [xyzPoints2, reprojErrors2] = triangulate_v4(Ks, Rs, ts, allxy);
        %disp('triangule - check pass')
        %disp(xyzPoints2(1:4,:))
        refinedErrors=reprojErrors2;

        % pad if necessary
        xyzRefinedPoints(end+1:size(allxy,1),:)=NaN;
        refinedErrors(end+1:size(allxy,1),:)=NaN;

        % set zeros to NaN
        xyzRefinedPoints(xyzRefinedPoints==0)=NaN;
        refinedErrors(refinedErrors==0)=NaN;

        % set should-be zeros to NaN
        xyzRefinedPoints(sum(isfinite(allxy),2)<3,:)=NaN;
        refinedErrors(sum(isfinite(allxy),2)<3)=NaN;

        % display results
        app.msg{end+1}=['Final camera bundle adjustment error: ',num2str(inanmean(refinedErrors))];
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');


      else % no secondary cameras
        allcams=pcams;
      end % end of check for at least one secondary camera


      %% SCALE SCENE %%

      % scale operations order of priority
      % 1) 3D locations of reference points
      % 2) checkbox to use among-camera distances
      % 3) wand length
      % 4) unscaled

      % using known 3D point alignment
      if app.AlignmenttypeDropDown.Value==6 % known 3D point alignment
        % display info
        app.msg{end+1}='Setting scene scale from known 3D point locations';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');

        % get ideal distance data
        iXYZ=app.axisPts.data(:,end-2:end);
        iDist=[];
        for i=1:size(iXYZ,1)
          for j=i+1:size(iXYZ,1)
            iDist=[iDist;rnorm(iXYZ(i,:)-iXYZ(j,:))];
          end
        end

        % get current distances
        axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);
        aDist=[];
        for i=1:size(axyz,1)
          for j=i+1:size(axyz,1)
            aDist=[aDist;rnorm(axyz(i,:)-axyz(j,:))];
          end
        end
        scaleCalc=iDist./aDist;
        scaleFactor=inanmean(scaleCalc);

        scaleScore=100*std(scaleCalc(isfinite(scaleCalc)))/inanmean(scaleCalc);

        app.msg{end+1}=[sprintf('Scale error: %.2f%%',scaleScore)];
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');


      % using inter-camera distances
    elseif app.SetscenescalefrommeasuredcameracameradistancesCheckBox.Value && ...
          sum(isfinite(table2array(app.CameraDistanceTable.Data(:,3))))>0
        % display info
        app.msg{end+1}='Using camera distances to set scene scale';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
        scaleD=[];

        % re-order the camera locations data to match the original camera
        % order (in case of re-arrangement of secondary cameras)
        orderedLocations=[];
        for i=1:numel(allcams)
          orderedLocations(i,1)=allcams(i);
          cxyz=refinedPoses.Location{i};
          cxyz=(-refinedPoses.Orientation{i}'*cxyz')';
          orderedLocations(i,2:4)=cxyz;
        end
        orderedLocations=sortrows(orderedLocations,1);
        orderedLocations(:,1)=[];
        for i=1:size(app.CameraDistanceTable.Data,1)
          scaleD(i,1)=table2array(app.CameraDistanceTable.Data(i,3));

          scaleD(i,2)=rnorm(orderedLocations(table2array(app.CameraDistanceTable.Data(i,1)),:)-...
            orderedLocations(table2array(app.CameraDistanceTable.Data(i,2)),:));
        end
        scaleCalc=scaleD(:,1)./scaleD(:,2);
        scaleFactor=inanmean(scaleCalc);
        scaleQuality=100*std(scaleCalc(isfinite(scaleCalc)))/inanmean(scaleCalc);
        if isnan(scaleFactor)
          % display info
          app.msg{end+1}='Scale information not present, setting scale to 1.0';
          app.msg{end+1}=sprintf('Raw camera distances: %s ',sprintf('%.4f ',scaleD(:,2)));
          scaleFactor=1;
        else
          app.msg{end+1}=sprintf('Scaled camera distances: %s ',sprintf('%.4f ',scaleD(:,2)*scaleFactor));
          app.msg{end+1}=sprintf('Scale error: %.2f',scaleQuality);
        end
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');


      elseif size(wxy,1)>0 % scale from paired point (i.e. wand) length
        % display info
        app.msg{end+1}='Using wand length to set scene scale';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
        % wand scaling
        wxyz=nan(size(wxy,1),6);
        wxyz(:,1:3)=xyzRefinedPoints(1:size(wxy,1),1:3);
        wxyz(:,4:6)=xyzRefinedPoints(size(wxy,1)+1:size(wxy,1)*2,1:3);
        wxyz(wxyz==0)=NaN; % exact zeros should be NaNs
        wl=inanmean(rnorm(wxyz(:,1:3)-wxyz(:,4:6)));
        if isnan(wl)
          app.msg{end+1}='... but the wand length could not be calculated.';
          app.msg{end+1}='Leaving the calibration unscaled';
          app.msgTxtArea.Value=app.msg;
          app.msgTxtArea.scroll('bottom');
          scaleFactor=1;
        else
          scaleFactor=app.InterpointdistanceEditField.Value/wl;
        end
      else
        app.msg{end+1}='No scale information is present, leaving the calibration unscaled.';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
        scaleFactor=1;
      end
      if isfinite(scaleFactor)==false
        scaleFactor=1;
      end

      % apply scale
      refinedPoses.Location(:)=cellfun(@(x) x*scaleFactor,refinedPoses.Location(:),'UniformOutput',0);
      xyzRefinedPoints=xyzRefinedPoints*scaleFactor;


      %% ALIGN SCENE %%

      % get alignment type
      alType=app.AlignmenttypeDropDown.Value;
      lhFlag=false; % set to true if a left-handed coordinate system is detected in the xyzRefinedPoints
      if alType==5  % azimuth & elevation
        % display info
        app.msg{end+1}='Applying azimuth and elevation alignment';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
        try
          % get the data
          az=app.AzimuthangleofthelastcameradegreesEditField.Value;
          el=app.ElevationangleofthelastcameradegreesEditField.Value;

          % put the last camera in the standard position even if
          % it was a secondary camera
          idx=find(allcams==max(allcams));
          if idx~=numel(allcams)
            rotm=refinedPoses.Orientation{idx}';
            o=refinedPoses.Location{idx};
            for i=1:size(refinedPoses,1)
              refinedPoses.Location{i}=refinedPoses.Location{i}+(refinedPoses.Orientation{i}*o')';
              refinedPoses.Orientation{i}=refinedPoses.Orientation{i}*rotm;
            end
          end

          % perform rotation to "standard" pose with cameras looking along +X, +Z up and +Y left and re-triangulate
          rotmA=[0,-1,0;0,0,-1;1,0,0];
          %refinedPoses.Location(:)=cellfun(@(x) x*rotm,refinedPoses.Location(:),'UniformOutput',0);
          refinedPoses.Orientation(:)=cellfun(@(x) x*rotmA,refinedPoses.Orientation(:),'UniformOutput',0);

          % now apply the measured azimuth and elevation (away from
          % the standard pose as described above)

          rotm=[cosd(az),-sind(az),0;sind(az),cosd(az),0;0,0,1];
          rotm2=[cosd(el),0,sind(el);0,1,0;-sind(el),0,cosd(el)];
          %refinedPoses.Location(:)=cellfun(@(x) x*rotm2*rotm,refinedPoses.Location(:),'UniformOutput',0);
          refinedPoses.Orientation(:)=cellfun(@(x) x*rotm2*rotm,refinedPoses.Orientation(:),'UniformOutput',0);

          % re-triangulate with the new poses
          for i=1:numel(allcams)
            Rs{i}=refinedPoses.Orientation{i};
            ts{i}=refinedPoses.Location{i}';
          end
          [xyzRefinedPoints, refinedErrors] = triangulate_v4(Ks, Rs, ts, allxy);
          xyzRefinedPoints(isfinite(xyzRefinedPoints)~=true)=NaN;

          % put the origin at the middle of the point cloud
          meanPt=inanmean(xyzRefinedPoints);
          for i=1:numel(allcams)
            refinedPoses.Location{i}=refinedPoses.Location{i}+(refinedPoses.Orientation{i}*meanPt')';
          end
          %refinedPoses.Location(:)=cellfun(@(x) x-meanPt,refinedPoses.Location(:),'UniformOutput',0);


          % re-triangulate (again) with the new poses
          for i=1:numel(allcams)
            Rs{i}=refinedPoses.Orientation{i};
            ts{i}=refinedPoses.Location{i}';
          end
          [xyzRefinedPoints, refinedErrors] = triangulate_v4(Ks, Rs, ts, allxy);

        catch
          % display info
          app.msg{end+1}='Alignment operation failed!';
          app.msgTxtArea.Value=app.msg;
          app.msgTxtArea.scroll('bottom');

        end
      elseif alType==1 % 4 point axis

        if size(axy,1)==4
          app.msg{end+1}='Applying 4-point axis alignment';
          axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);

          o=axyz(1,:); % origin
          axyz=axyz-repmat(o,4,1); % subtract origin
          [th,ph] = cart2sph(axyz(2,1),axyz(2,2),axyz(2,3)); % +X axis position
          rotm=[cos(th),-sin(th),0;sin(th),cos(th),0;0,0,1]; % un-yaw
          rotm2=[cos(ph),0,sin(ph);0,1,0;-sin(ph),0,cos(ph)]'; % un-pitch
          axyz=axyz*rotm*rotm2;
          [th2]=cart2pol(axyz(3,2),axyz(3,3)); % un-roll
          rotm3=[1,0,0;0,cos(th2),-sin(th2);0,sin(th2),cos(th2)];
          axyz=axyz*rotm3;

          % final fix on z
          vZ=axyz(4,:)./norm(axyz(4,:));
          ang=acos(dot(vZ,[0,0,sign(vZ(3))]));
          ax=cross([0,0,sign(vZ(3))],vZ);
          ax=ax./norm(ax);
          c=cos(ang);
          s=sin(ang);
          t=(1-c);
          rotm5=[t*ax(1)^2+c,t*ax(1)*ax(2)-ax(3)*s,t*ax(1)*ax(3)+ax(2)*s;...
            t*ax(1)*ax(2)+ax(3)*s,t*ax(2)^2+c,t*ax(2)*ax(3)-ax(1)*s;...
            t*ax(1)*ax(3)-ax(2)*s,t*ax(2)*ax(3)+ax(1)*s,t*ax(3)^2+c];
          axyz=axyz*rotm5;

          % check for a left-handed CS
          if sum(sum(sign(axyz(2:4,:).*eye(3))==-1))>0
            app.msg{end+1}='Detected a left-hand coordinate system - this will be corrected,';
            app.msg{end+1}='in the DLT coefficients and in the camera extrinsics.';
            lhFlag=true;
            rsign=sign(axyz(2:4,:)).*eye(3);
          else
            rsign=eye(3);
          end

          % push to calibration
          for i=1:size(refinedPoses,1)
            refinedPoses.Location{i}=refinedPoses.Location{i}+(refinedPoses.Orientation{i}*o')';
            refinedPoses.Orientation{i}=refinedPoses.Orientation{i}*rotm*rotm2*rotm3*rotm5*rsign;
          end

          % re-triangulate (again) with the new poses
          for i=1:numel(allcams)
            Rs{i}=refinedPoses.Orientation{i};
            ts{i}=refinedPoses.Location{i}';
          end
          [xyzRefinedPoints, refinedErrors] = triangulate_v4(Ks, Rs, ts, allxy);
          axyz2=xyzRefinedPoints(end-size(axy,1)+1:end,:);

          % get non-orthogonality
          axyz3=axyz2-repmat(axyz2(1,:),4,1);
          axyz3(2,:)=axyz3(2,:)./norm(axyz3(2,:));
          axyz3(3,:)=axyz3(3,:)./norm(axyz3(3,:));
          axyz3(4,:)=axyz3(4,:)./norm(axyz3(4,:));
          ia(1)=acosd(dot(axyz3(2,:),axyz3(3,:)));
          ia(2)=acosd(dot(axyz3(3,:),axyz3(4,:)));
          ia(3)=acosd(dot(axyz3(2,:),axyz3(4,:)));
          ia=abs(ia-90);
          app.msg{end+1}=sprintf('Completed 4-point axis alignment, maximum non-orthogonality was %.2f degrees.', ...
            max(ia));
          app.msgTxtArea.Value=app.msg;
        else
          app.msg{end+1}=['4-point axis alignment selected, but',...
            ' no appropriate axis data present; no alignment performed.'];
          app.msgTxtArea.Value=app.msg;
        end
        app.msgTxtArea.scroll('bottom');
      elseif alType==2 % 3 point axis
        app.msg{end+1}='Applying 3-point axis alignment';
        app.msgTxtArea.Value=app.msg;
        if size(axy,1)==3
          axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);
          o=axyz(1,:);
          axyz=axyz-repmat(o,3,1); % subtract origin
          [th,ph] = cart2sph(axyz(2,1),axyz(2,2),axyz(2,3));
          rotm=[cos(th),-sin(th),0;sin(th),cos(th),0;0,0,1];
          rotm2=[cos(ph),0,sin(ph);0,1,0;-sin(ph),0,cos(ph)]';
          axyz(2,:)=axyz(2,:)*rotm*rotm2;
          axyz(3,:)=axyz(3,:)*rotm*rotm2;
          [th2]=cart2pol(axyz(3,2),axyz(3,3));
          rotm3=[1,0,0;0,cos(th2),-sin(th2);0,sin(th2),cos(th2)];
          axyz(3,:)*rotm3;

          % push to calibration
          %rSign=[1,0,0;0,1,0;0,0,-1]; % left-hand correction
          for i=1:size(refinedPoses,1)
            refinedPoses.Location{i}=refinedPoses.Location{i}+(refinedPoses.Orientation{i}*o')';
            refinedPoses.Orientation{i}=refinedPoses.Orientation{i}*rotm*rotm2*rotm3;
          end

          % re-triangulate (again) with the new poses
          for i=1:numel(allcams)
            Rs{i}=refinedPoses.Orientation{i};
            ts{i}=refinedPoses.Location{i}';
          end
          [xyzRefinedPoints, refinedErrors] = triangulate_v4(Ks, Rs, ts, allxy);
          axyz2=xyzRefinedPoints(end-size(axy,1)+1:end,:);

          % get non-orthogonality
          axyz3=axyz2-repmat(axyz2(1,:),3,1);
          axyz3(2,:)=axyz3(2,:)./norm(axyz3(2,:));
          axyz3(3,:)=axyz3(3,:)./norm(axyz3(3,:));
          ia=acosd(dot(axyz3(2,:),axyz3(3,:)));
          app.msg{end+1}=sprintf('Completed 3-point axis alignment, maximum non-orthogonality was %.2f degrees.', ...
            abs(90-ia));
          app.msgTxtArea.Value=app.msg;
        else
          app.msg{end+1}=['3-point axis alignment selected, but',...
            ' no appropriate axis data present; no alignment performed.'];
          app.msgTxtArea.Value=app.msg;
        end
        app.msgTxtArea.scroll('bottom');
      elseif alType==3 % plane
        app.msg{end+1}='Aligning to a plane';
        app.msgTxtArea.Value=app.msg;
        % get the xyz time-series
        axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);

        % put the origin at the mean of the axis
        o=inanmean(axyz);
        axyz=axyz-repmat(o,size(axyz,1),1); % subtract origin

        % do a principal components analysis to find the rotation that puts the least variation in Z,
        % i.e. exactly how a plane should look. Implement with SVD to avoid needing the stats toolbox
        [~,~,V]=svd(axyz(sum(isfinite(axyz),2)>0,:));

        % make sure V is a proper rotation matrix
        if det(V)<0
          V=-V;
        end
        rotm5=V;

        axyz=axyz*rotm5;

        % push to calibration
        %refinedPoses.Location(:)=cellfun(@(x) x-o,refinedPoses.Location(:),'UniformOutput',0);
        %refinedPoses.Location(:)=cellfun(@(x) x*rotm5,refinedPoses.Location(:),'UniformOutput',0);
        %refinedPoses.Orientation(:)=cellfun(@(x) x*rotm5,refinedPoses.Orientation(:),'UniformOutput',0);

        % re-triangulate (again) with the new poses
        %[xyzRefinedPoints,refinedErrors] = triangulateMultiview(pointTracks,refinedPoses,intrinsics);
        %xyzRefinedPoints(isfinite(xyzRefinedPoints)~=true)=NaN; % sensible output for unknown points

        %push to calibration, use a loop - easier to read than
        %the cellfun bits of simpleWand
        for i=1:size(refinedPoses,1)
          refinedPoses.Location{i}=refinedPoses.Location{i}+(refinedPoses.Orientation{i}*o')';
          refinedPoses.Orientation{i}=refinedPoses.Orientation{i}*rotm5;
        end

        % re-triangulate (again) with the new poses
        for i=1:numel(allcams)
          Rs{i}=refinedPoses.Orientation{i};
          ts{i}=refinedPoses.Location{i}';
        end
        [xyzRefinedPoints, refinedErrors] = triangulate_v4(Ks, Rs, ts, allxy);
        xyzRefinedPoints(isfinite(xyzRefinedPoints)~=true)=NaN; % sensible output for unknown points
        axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);

        % if the average Z point is negative, rotate by 180 about X
        if inanmean(xyzRefinedPoints(:,3))<0
          rotm6=[1,0,0;0,cosd(180),-sind(180);0,sind(180),cosd(180)];

          for i=1:size(refinedPoses,1)
            refinedPoses.Orientation{i}=refinedPoses.Orientation{i}*rotm6;
          end

          % re-triangulate (again) with the new poses
          for i=1:numel(allcams)
            Rs{i}=refinedPoses.Orientation{i};
            ts{i}=refinedPoses.Location{i}';
          end
          [xyzRefinedPoints, refinedErrors] = triangulate_v4(Ks, Rs, ts, allxy);
          xyzRefinedPoints(isfinite(xyzRefinedPoints)~=true)=NaN; % sensible output for unknown points
          axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);
        end
        app.msgTxtArea.scroll('bottom');

      elseif alType==4 % gravity
        if size(axy,1)>5
          % get the xyz time-series
          axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);
          idx=find(isfinite(axyz(:,1)));
          o=axyz(idx(1),:); % this will be the origin

          % fit a 2nd order polynomial and get the derivative
          g=app.polyFitGravity(axyz);
          app.msg{end+1}=[sprintf('gravity measured as %0.1f percent of actual',100*rnorm(g)/9.81)];

          % rotate to point g along -Z
          % turn the angle-axis rotation into a rotation matrix with the
          % nifty exponential map method
          vZ=g./norm(g);
          ang=acos(dot(vZ,[0,0,-1]));
          ax=cross([0,0,-1],vZ);
          ax=ax./norm(ax);
          axM=[0,-ax(3),ax(2);ax(3),0,-ax(1);-ax(2),ax(1),0];
          rotm5=eye(3)+sin(ang)*axM+(1-cos(ang))*axM^2;
          %axyzR=(axyz*rotm5);
          %axyzR=(axyz-o)*rotm5;

          %push to calibration, use a loop - easier to read than
          %the cellfun bits of simpleWand
          for i=1:size(refinedPoses,1)
            refinedPoses.Location{i}=refinedPoses.Location{i}+(refinedPoses.Orientation{i}*o')';
            refinedPoses.Orientation{i}=refinedPoses.Orientation{i}*rotm5;
          end

          % re-triangulate (again) with the new poses
          for i=1:numel(allcams)
            Rs{i}=refinedPoses.Orientation{i};
            ts{i}=refinedPoses.Location{i}';
          end
          [xyzRefinedPoints, refinedErrors] = triangulate_v4(Ks, Rs, ts, allxy);
          axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);


        else
          app.msg{end+1}=['gravity alignment selected, but',...
            ' no appropriate data present; no alignment performed.'];
        end
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
      elseif alType==6 % 3+ known 3D points
        axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);

        % find points that are non-NaN in the axis points and known
        % locations arrays
        idx=find(sum(isfinite(axyz.*iXYZ),2)==3);
        axyzm=inanmean(axyz(idx,:));
        alp=axyz-axyzm;
        icm=inanmean(iXYZ(idx,:));
        ic=iXYZ-icm;

        % get the rotation using SVD
        % https://igl.ethz.ch/projects/ARAP/svd_rot.pdf
        H = (alp(idx,:))'*(ic(idx,:));
        [U,~,V]=svd(H);
        ar=eye(size(V,1)); % anti-reflection shim
        ar(end,end)=det(V*U');
        rotm=(V*ar*U')';


        %push to calibration, use a loop - easier to read than
        %the cellfun bits of simpleWand
        for i=1:size(refinedPoses,1)
          refinedPoses.Location{i}=refinedPoses.Location{i}+(refinedPoses.Orientation{i}*axyzm')';
          refinedPoses.Orientation{i}=refinedPoses.Orientation{i}*rotm;
          refinedPoses.Location{i}=refinedPoses.Location{i}-(refinedPoses.Orientation{i}*icm')';
        end

        % re-triangulate (again) with the new poses
        %[xyzRefinedPoints,refinedErors] = triangulateMultiview(pointTracks,refinedPoses,intrinsics);
        for i=1:numel(allcams)
          Rs{i}=refinedPoses.Orientation{i};
          ts{i}=refinedPoses.Location{i}';
        end
        [xyzRefinedPoints, refinedErrors] = triangulate_v4(Ks, Rs, ts, allxy);
        %axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);
        axyz2=xyzRefinedPoints(end-size(axy,1)+1:end,:);
        foo=rnorm(axyz2(idx,:)-(ic(idx,:)+icm));
        app.msg{end+1}='Alignment to known 3D points complete.';
        app.msg{end+1}=sprintf('Median alignment point dimensional error: %.4f',median(foo(isfinite(foo))));
        app.msg{end+1}=sprintf('Maximum alignment point dimensional error: %.4f',max(foo));
      else
        % display info
        app.msg{end+1}='You have selected an unimplemented alignment type';
        app.msg{end+1}='No alignment operations performed';
      end
      app.msgTxtArea.Value=app.msg;
      app.msgTxtArea.scroll('bottom');

      %% pass data back out %%

      % pad & fix any empties (created by outlier removal)
      xyzRefinedPoints(end+1:size(allxy,1),:)=NaN;
      xyzRefinedPoints(sum(xyzRefinedPoints==0,2)==3,:)=NaN;
      xyzRefinedPoints(isfinite(xyzRefinedPoints)~=true)=NaN;
      refinedErrors(end+1:size(allxy,1),:)=NaN;
      refinedErrors(refinedErrors==0)=NaN;
      refinedErrors(isfinite(refinedErrors)~=true)=NaN;

      % % fix left-handedness if necessary
      % if lhFlag==true
      %     xyzRefinedPoints=xyzRefinedPoints.*lhCorrector;
      % end

      % split xyzRefinedPoints back to wand, bkg, axis
      if size(axy,1)>0
        axyz=xyzRefinedPoints(end-size(axy,1)+1:end,:);
        xyzRefinedPoints(end-size(axy,1)+1:end,:)=[];

        aerr=refinedErrors(end-size(axy,1)+1:end,:);
        refinedErrors(end-size(axy,1)+1:end,:)=[];
      else
        axyz=zeros(0,3);
        aerr=zeros(0,1);
      end
      if size(bxy,1)>0
        bxyz=xyzRefinedPoints(end-size(bxy,1)+1:end,:);
        xyzRefinedPoints(end-size(bxy,1)+1:end,:)=[];

        berr=refinedErrors(end-size(bxy,1)+1:end,:);
        refinedErrors(end-size(bxy,1)+1:end,:)=[];
      else
        bxyz=zeros(0,3);
        berr=zeros(0,1);
      end
      if size(wxy,1)>0

        wxyz=nan(size(wxy,1),6);
        wxyz(:,1:3)=xyzRefinedPoints(1:size(xyzRefinedPoints,1)/2,1:3);
        wxyz(:,4:6)=xyzRefinedPoints(size(xyzRefinedPoints,1)/2+1:end,1:3);

        werr=nan(size(wxy,1),2);
        werr(:,1)=refinedErrors(1:size(xyzRefinedPoints,1)/2,1);
        werr(:,2)=refinedErrors(size(xyzRefinedPoints,1)/2+1:end,1);

        wandLen=rnorm(wxyz(:,1:3)-wxyz(:,4:6));

      else
        wxyz=zeros(0,6);
        werr=zeros(0,2);
        wandLen=NaN;
      end

      % push data back out
      if ~isempty(wxyz)
        app.wandPts.xyz=wxyz;
        app.wandPts.rmse=werr;
        app.wandPts.wandLength=rnorm(wxyz(:,1:3)-wxyz(:,4:6));
      end

      if ~isempty(bxyz)
        app.bkgPts.xyz=bxyz;
        app.bkgPts.rmse=berr;
      end

      if ~isempty(axyz)
        app.axisPts.xyz=axyz;
        app.axisPts.rmse=aerr;
      end

      % push the extrinsics back out
      for i=1:numel(allcams)
        app.extrinsics.poses{allcams(i)}=refinedPoses.Orientation{i};
        app.extrinsics.positions{allcams(i)}=refinedPoses.Location{i};
      end
      app.intrinsics=intrinsics(allcams);

      % compute DLT coefficients
      % numeric method
      dlt_computeCoefficients(app);

      % algebraic method - needs debugging
      % c2=dlt_convertCoefficients(app); % doesn't work well with non-SBA extrinsics?
      % app.dltcoefs=c2;

      app.msg{end+1}='DLT conversion complete, camera residuals are:';
      app.msg{end+1}=[num2str(app.rmse)];
      app.msgTxtArea.Value=app.msg;
      app.msgTxtArea.scroll('bottom');

      % get wand score
      if size(wxy,1)>0
        wxy2=[allxy(1:size(wxy,1),:),allxy(size(wxy,1)+1:size(wxy,1)*2,:)]; % undistorted wand points
        [xyz1,rmse1]=dlt_reconstruct(app.dltcoefs(:,allcams),wxy2(:,1:app.numCams*2));
        [xyz2,rmse2]=dlt_reconstruct(app.dltcoefs(:,allcams),wxy2(:,app.numCams*2+1:end));
        wandLen=rnorm(xyz1-xyz2);
        app.msg{end+1}=sprintf('Wand score: %.2f',std(wandLen(isfinite(wandLen)))/inanmean(wandLen)*100);
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom')
      end

      % enable the following after a successful calibration
      app.ExportDLTcoefficientsMenu.Enable=true;
      app.ExportcameraprofileMenu.Enable=true;

      % plot the scene
      plot3DReconstruct(app);

      % update the UI
      updateUnpairedPointsGrid(app);
      updatePairedPointsGrid(app);
      updateAxisPointsGrid(app);
      app.identifyoutliersandrecalibrateMenu.Enable=true;

      app.easyWandUIFigure.Pointer='arrow';

    end

    % Selection change function: TabGroup
    function TabGroupSelectionChanged(app, event)
      selectedTab = app.TabGroup.SelectedTab;

      %disp('tab group selection changed')
    end

    % Value changed function: ShowpointsfromDropDown
    function ShowpointsfromDropDownValueChanged(app, event)
      value = app.ShowpointsfromDropDown.Value;
      updateUnpairedPointsGrid(app)
    end

    % Cell edit callback: unpairedPointsTable
    function unpairedPointsTableCellEdit(app, event)
      indices = event.Indices;
      newData = event.NewData;
      app.bkgPts.outlier(indices(1))=~app.bkgPts.outlier(indices(1)); % flip the true-false value
    end

    % Menu selected function: SavethisprojectMenu
    function SavethisprojectMenuSelected(app, event)

      % get file save name and location
      [filename, pathname] = uiputfile('*.mat', 'Save easyWand6 project as?');
      if filename==0
        app.msgTxtArea.Value={'Project save canceled'};
        return
      end

      % start creating the data save structure
      ew6Save=makeSaveBundle(app,1); % create the save structure with data & UI components

      % setup filename with appended _easyWand6Data suffix, but don't double-up the
      % suffix
      filename2 = filename(1:end-4);
      s=strfind(filename2,'_easyWand6Data');
      if isempty(s)==false
        filename2=filename2(1:s-1);
      end
      filename2 = [filename2,'_easyWand6Data.mat'];
      ew6Save.savePath=pathname;
      ew6Save.saveName=filename(1:end-4);
      save([pathname,filename2],'ew6Save','-v7.3');
      app.msg{end+1}=['easyWand6 project saved as ',pathname,filename2];
      app.msgTxtArea.Value=app.msg;
      app.msgTxtArea.scroll('bottom');

    end

    % Menu selected function: LoadasavedprojectMenu
    function LoadasavedprojectMenuSelected(app, event)

      % browse for the project file
      if verLessThan('matlab','24')
      [filename,pathname] = uigetfile({'*_easyWand6Data.mat','easyWand project';'*_simpleWandData.mat','simpleWand project';'*.mat','MATLAB file'},...
        'Pick an easyWand6 or simpleWand file to load');
      else % use the less capable r2025a+ uigetfile
        [filename,pathname] = uigetfile({'*.mat','MATLAB data file'},...
        'Pick an easyWand6 or simpleWand data file to load');
      end
      if filename==0
        app.msg{end+1}='Load project canceled';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
        return
      end

      % load the file
      vars = whos('-file',[pathname,filename]);
      if ismember('ew6Save', {vars.name})
        load([pathname,filename],'ew6Save');
      elseif ismember('swSave', {vars.name})
        load([pathname,filename],'swSave');
        ew6Save=swSave;
      else
        app.msg{end+1}=(['Sorry, no easyWand6 or simpleWand data were found in ',pathname,filename]);
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
        return
      end


      % check version - not really relevant anymore for easyWand6
      try
        if ew6Save.easyWand_version(1)<0 & ew6Save.easyWand_version(1)<6
          app.msg{end+1}=('Sorry, this project is from an older version of simpleWand and may load incorrectly');
          app.msgTxtArea.Value=app.msg;
          app.msgTxtArea.scroll('bottom');
        end
      catch
      end

      % restore data
      fn=fieldnames(ew6Save.data);
      for i=1:numel(fn)
        eval(['a=isstruct(ew6Save.data.',fn{i},');'])
        if a
          eval(['fn2=fieldnames(ew6Save.data.',fn{i},');'])
          for j=1:numel(fn2)
            eval(['app.',fn{i},'.',fn2{j},'=ew6Save.data.',fn{i},'.',fn2{j},';'])
          end
        else
          eval(['app.',fn{i},'=ew6Save.data.',fn{i},';'])
        end
      end

      % small compatibility fixes for older saved data files
      if isfield(ew6Save.data.axisPts,'rmse')==false
        app.axisPts.data.rmse=nan(size(app.axisPts.ID,1),1);
      end

      % populate the alignment points menu from the unpaired points menu;
      % alignment points menu is new enough that it is not saved in older
      % files, but the two menus share identical structures
      app.ShowalignmentpointsfromDropDown.Items=app.ShowpointsfromDropDown.Items;
      app.ShowalignmentpointsfromDropDown.ItemsData=app.ShowpointsfromDropDown.ItemsData;
      
      % update UI
      updateUnpairedPointsGrid(app);
      updatePairedPointsGrid(app);
      updateAxisPointsGrid(app);
      if numel(app.UnpairedpointsfileEditField.Value)>2
        app.OperationsMenu.Enable=true;
      end
      try
        plot3DReconstruct(app)
      catch ME
        % nothing
      end

      % turn on the UI
      app.NewprojectMenu.Enable=false;
      app.LoadasavedprojectMenu.Enable=false;
      app.SavethisprojectMenu.Enable=true;
      app.ExportcameraprofileMenu.Enable=true;
      app.DataMenu.Enable=true;
      app.updateAlignmentOptionVisibility();
      app.ExporttoworkspaceMenu.Enable=true;
      if size(app.cameraTable.Data,1)>2
        app.IntrinsicparamatersDropDown.Enable=true;
      end

      app.msg{end+1}=(['Finished loading data from ',pathname,filename]);
      app.msgTxtArea.Value=app.msg;
      app.msgTxtArea.scroll('bottom');
    end

    % Menu selected function: ExportDLTcoefficientsMenu
    function ExportDLTcoefficientsMenuSelected(app, event)
      % get save name and path
      [fname,pname]=uiputfile({'*.csv','comma-separated value files (*.csv)'});
      if fname~=0
        if strcmpi(fname(end-3:end),'.csv')
          fname=fname(1:end-4);
        end
        dlmwrite([pname,fname,'_dltCoefs.csv'],app.dltcoefs,',');
        app.msg{end+1}=(['DLT coefficients exported as ',pname,fname,'_dltCoefs.csv']);
        for i=1:app.numCams
          name=['_cam',num2str(i),'Tforms'];
          if sum(abs(app.intrinsics(i).RadialDistortion(:)))>0
            create_Tforms(app.intrinsics(i).FocalLength,app.intrinsics(i).PrincipalPoint,...
              [app.intrinsics(i).RadialDistortion(1:2),...
              app.intrinsics(i).TangentialDistortion,app.intrinsics(i).RadialDistortion(3)],...
              app.intrinsics(i).PrincipalPoint*2,[pname,fname,name]); % assume the principal point is centered ==> could be fixed later
            app.msg{end+1}=sprintf('Exported undistortion tforms for camera %.0f as %s',i,name);
          end
          %create_Tforms(fl,pp,kc,res,[pname,fname,name]);
        end
      else
        app.msg{end+1}=(['Export DLT coefficients canceled.']);
      end
      app.msgTxtArea.Value=app.msg;
      app.msgTxtArea.scroll('bottom');
    end

    % Menu selected function: LicenseMenu
    function LicenseMenuSelected(app, event)
      msgbox(app.licenseText);
    end

    % Menu selected function: AboutMenu
    function AboutMenuSelected(app, event)
      txt=sprintf('This is easyWand6 version %d.%d.%d',app.version(1),app.version(2),app.version(3));
      msgbox(txt)
    end

    % Menu selected function: QuitMenu
    function QuitMenuSelected(app, event)
      delete(app);
    end

    % Menu selected function: clearoutlierdesignationsMenu
    function clearoutlierdesignationsMenuSelected(app, event)
      app.msg{end+1}='Clear outlier designations selected';
      app.msgTxtArea.Value=app.msg;
      app.msgTxtArea.scroll('bottom');
      app.bkgPts.outlier=false(size(app.bkgPts.data,1),1);
      updateUnpairedPointsGrid(app)

      app.wandPts.outlier=false(size(app.wandPts.data,1),2);
      updatePairedPointsGrid(app)

      clearPlot(app)
    end

    % Value changed function: ShowpointsfromDropDown_2
    function ShowpointsfromDropDown_2ValueChanged(app, event)
      updatePairedPointsGrid(app)
    end

    % Cell edit callback: pairedPointsTable
    function pairedPointsTableCellEdit(app, event)
      indices = event.Indices;
      newData = event.NewData;
      pt=-mod(app.ShowpointsfromDropDown_2.Value,2)+2;
      app.wandPts.outlier(indices(1),pt)=~app.wandPts.outlier(indices(1),pt); % flip the true-false value
    end

    % Value changed function: AlignmenttypeDropDown
    function AlignmenttypeDropDownValueChanged(app, event)
      value = app.AlignmenttypeDropDown.Value;
            app.updateAlignmentOptionVisibility();
    end

    % Menu selected function: ExportcameraprofileMenu
    function ExportcameraprofileMenuSelected(app, event)
      % setup camera profile array
      camProf=[app.cameraTable.Data.num, app.cameraTable.Data.f,app.cameraTable.Data.Uo*2, ...
        app.cameraTable.Data.Vo*2,app.cameraTable.Data.Uo,app.cameraTable.Data.Vo, ...
        app.cameraTable.Data.num*0+1,app.cameraTable.Data.d2,app.cameraTable.Data.d4,...
        app.cameraTable.Data.num*0,app.cameraTable.Data.num*0,app.cameraTable.Data.d6];

      % get save name and path
      [f,p]=uiputfile({'*.txt','Text files (*.txt)'},'Save profile as');

      if f~=0
        if strcmpi(f(end-3:end),'.txt')
          f=f(1:end-4);
        end
        dlmwrite([p,f,'_profile.txt'],camProf,' ');
        app.msg{end+1}=sprintf('Profile saved to %s%s_profile.txt',p,f);
      else
        app.msg{end+1}='Profile not saved';
      end

      app.msgTxtArea.Value=app.msg;
      app.msgTxtArea.scroll('bottom');


    end

    % Menu selected function: SetimageoriginMenu
    function SetimageoriginMenuSelected(app, event)
      % lower left origin isn't ready yet
      bn = questdlg('Where is the image coordinate origin?','Image origin?','Upper left','Lower left','Upper left');
      if strcmpi('Lower left',bn)
        app.imageOriginLowerLeft=1;
      else
        app.imageOriginLowerLeft=0;
      end
    end

    % Menu selected function: identifyoutliersandrecalibrateMenu
    function identifyoutliersandrecalibrateMenuSelected(app, event)
      app.msg{end+1}='Setting worst 5% of points as outliers & recalibrating';
      % accumulate all errors
      allrmse=reshape(app.wandPts.rmse,numel(app.wandPts.rmse),1);
      allrmse=[allrmse;app.bkgPts.rmse];
      allrmse=sort(allrmse);
      allrmse(isnan(allrmse))=[];

      % find the cutoff
      cutoff=allrmse(round(numel(allrmse)*0.95));
      app.msg{end+1}=sprintf('Error cutoff was %.2f',cutoff);

      % set new outliers
      idx1=find(app.wandPts.rmse(:,1)>cutoff);
      app.wandPts.outlier(idx1,1)=true;
      idx2=find(app.wandPts.rmse(:,2)>cutoff);
      app.wandPts.outlier(idx2,2)=true;
      idx3=find(app.bkgPts.rmse(:,1)>cutoff);
      app.bkgPts.outlier(idx3,1)=true;
      app.msg{end+1}=sprintf('Marked %.0f paired points and %.0f unpaired points as outliers',...
        numel(idx1)+numel(idx2),numel(idx3));
      app.msgTxtArea.Value=app.msg;
      app.msgTxtArea.scroll('bottom');

      % re-run the calibration
      computecalibrationMenuSelected(app, event)

    end

    % Menu selected function: CheckforupdateMenu
    function CheckforupdateMenuSelected(app, event)
      v=webread('https://raw.githubusercontent.com/tlhedrick/easyWand6/main/easyWand6_version.txt');
      idx=strfind(v,'.');
      vMajor=str2num(v(1:idx(1)-1));
      vMinor=str2num(v(idx(1)+1:idx(2)-1));
      vPoint=str2num(v(idx(2)+1:end));

      % from the app
      versionMajor=app.version(1);
      versionMinor=app.version(2);
      versionPoint=app.version(3);

      if vMajor>versionMajor || (vMinor > versionMinor & vMajor==versionMajor) || (vPoint > versionPoint & vMajor==versionMajor & vMinor==versionMinor)
        ButtonName = questdlg('There is a newer version at https://github.com/tlhedrick/easyWand6', 'Version update?', 'OK','Go get it','OK');
        if strcmpi(ButtonName,'Go get it')
          web('https://github.com/tlhedrick/easyWand6')
        end
      else
        msgbox(sprintf('You are using the latest version, %.0f.%.0f.%.0f',versionMajor,versionMinor,versionPoint))
      end
    end

    % Menu selected function: ExporttoworkspaceMenu
    function ExporttoworkspaceMenuSelected(app, event)
      ew6Save=makeSaveBundle(app,0);
      [~,okpressed]=export2wsdlg({'Export easyWand6 data to workspace as:'},{'ew6'},{ew6Save});

      if okpressed
        app.msg{end+1}='Exported easyWand6 data to workspace.';
        app.msgTxtArea.Value=app.msg;
        app.msgTxtArea.scroll('bottom');
      end

    end

    % Menu selected function: restoreoriginalcameraparametersMenu
    function restoreoriginalcameraparametersMenuSelected(app, event)
      app.cameraTable.Data=app.cameraTableDataBak;
      app.intrinsics=[];
      app.msg{end+1}='Restored original camera intrinsic parameters.';
      app.msgTxtArea.Value=app.msg;
      app.msgTxtArea.scroll('bottom');
    end

    % Menu selected function: clearmessagesMenu
    function clearmessagesMenuSelected(app, event)
      app.msg={'.'};
      app.msgTxtArea.Value=app.msg;
    end

    % Value changed function: ShowalignmentpointsfromDropDown
    function ShowalignmentpointsfromDropDownValueChanged(app, event)
      %value = app.ShowalignmentpointsfromDropDown.Value;
      updateAxisPointsGrid(app)
      
    end

    % Menu selected function: GeteasySBAsourcecodeMenu
    function GeteasySBAsourcecodeMenuSelected(app, event)
      s=which('easySBA-source.zip'); % find the source code bundle
      [fname,pname]=uiputfile({'*.zip','ZIP archives'},'Save location for easySBA source code','easySBA-source.zip');
      if pname==0 & fname==0
        % user cancel
        return
      else
        [success,message]=copyfile(s,[pname,filesep,fname]);
        if success
          app.msg{end+1}=sprintf('easySBA sources written to %s',[pname,filesep,fname]);
          app.msgTxtArea.Value=app.msg;
          app.msgTxtArea.scroll('bottom')
        else
          app.msg{end+1}=sprintf('error trying to write easySBA sources file, operation failed');
          app.msg{end+1}=message;
          app.msgTxtArea.Value=app.msg;
          app.msgTxtArea.scroll('bottom')
        end
      end

    end

    % Menu selected function: Getefficient_pnpsourcecodeMenu
    function Getefficient_pnpsourcecodeMenuSelected(app, event)
      s=which('efficient_pnp.zip'); % find the source code bundle
      [fname,pname]=uiputfile({'*.zip','ZIP archives'},'Save location for efficient_pnp source code','efficient_pnp.zip');
      if pname==0 & fname==0
        % user cancel
        return
      else
        [success,message]=copyfile(s,[pname,filesep,fname]);
        if success
          app.msg{end+1}=sprintf('efficient_pnp sources written to %s',[pname,filesep,fname]);
          app.msgTxtArea.Value=app.msg;
          app.msgTxtArea.scroll('bottom')
        else
          app.msg{end+1}=sprintf('error trying to write efficient_pnp sources file, operation failed');
          app.msg{end+1}=message;
          app.msgTxtArea.Value=app.msg;
          app.msgTxtArea.scroll('bottom')
        end
      end
    end

    % Menu selected function: ManualMenu
    function ManualMenuSelected(app, event)
      web('https://biomech.web.unc.edu/easywand6-manual/')
    end

    % Drop down opening function: AlignmenttypeDropDown
    function AlignmenttypeDropDownOpening(app, event)
      % set color back in case it was changed
      app.AlignmenttypeDropDown.BackgroundColor=[0.9600 0.9600 0.9600];
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.easyWandUIFigure = uifigure('Visible', 'off');
      app.easyWandUIFigure.Name = 'easyWand6';
      app.ProjectMenu = uimenu(app.easyWandUIFigure);
      app.ProjectMenu.Text = 'Project';
      app.NewprojectMenu = uimenu(app.ProjectMenu);
      app.NewprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprojectMenuSelected, true);
      app.NewprojectMenu.Tooltip = {'Start a new project'};
      app.NewprojectMenu.Text = 'New project';
      app.LoadasavedprojectMenu = uimenu(app.ProjectMenu);
      app.LoadasavedprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadasavedprojectMenuSelected, true);
      app.LoadasavedprojectMenu.Tooltip = {'Load a saved project'};
      app.LoadasavedprojectMenu.Text = 'Load a saved project';
      app.SavethisprojectMenu = uimenu(app.ProjectMenu);
      app.SavethisprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SavethisprojectMenuSelected, true);
      app.SavethisprojectMenu.Tooltip = {'Save the current project as a MATLAB data file'};
      app.SavethisprojectMenu.Enable = 'off';
      app.SavethisprojectMenu.Text = 'Save this project';
      app.ExportcameraprofileMenu = uimenu(app.ProjectMenu);
      app.ExportcameraprofileMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportcameraprofileMenuSelected, true);
      app.ExportcameraprofileMenu.Tooltip = {'Export the camera profile in text format'};
      app.ExportcameraprofileMenu.Enable = 'off';
      app.ExportcameraprofileMenu.Text = 'Export camera profile';
      app.ExportDLTcoefficientsMenu = uimenu(app.ProjectMenu);
      app.ExportDLTcoefficientsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDLTcoefficientsMenuSelected, true);
      app.ExportDLTcoefficientsMenu.Tooltip = {'Export the calibration as DLT coefficients for use with DLTdv and elsewhere'};
      app.ExportDLTcoefficientsMenu.Enable = 'off';
      app.ExportDLTcoefficientsMenu.Text = 'Export DLT coefficients';
      app.ExporttoworkspaceMenu = uimenu(app.ProjectMenu);
      app.ExporttoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoworkspaceMenuSelected, true);
      app.ExporttoworkspaceMenu.Tooltip = {'Export app variables to the interactive workspace'};
      app.ExporttoworkspaceMenu.Enable = 'off';
      app.ExporttoworkspaceMenu.Text = 'Export to workspace';
      app.QuitMenu = uimenu(app.ProjectMenu);
      app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
      app.QuitMenu.Tooltip = {'Quit the simpleWand app'};
      app.QuitMenu.Text = 'Quit';
      app.DataMenu = uimenu(app.easyWandUIFigure);
      app.DataMenu.Enable = 'off';
      app.DataMenu.Text = 'Data';
      app.LoadpairedpointsMenu = uimenu(app.DataMenu);
      app.LoadpairedpointsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadpairedpointsMenuSelected, true);
      app.LoadpairedpointsMenu.Tooltip = {'Load wand or paired points; the physical distance between the paired points should be constant'};
      app.LoadpairedpointsMenu.Text = 'Load paired points';
      app.LoadunpairedpointsMenu = uimenu(app.DataMenu);
      app.LoadunpairedpointsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadunpairedpointsMenuSelected, true);
      app.LoadunpairedpointsMenu.Tooltip = {'Load a set of corresponding points; csv format with [u,v] columns for each camera.'};
      app.LoadunpairedpointsMenu.Text = 'Load unpaired points';
      app.LoadalignmentfileMenu = uimenu(app.DataMenu);
      app.LoadalignmentfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadalignmentfileMenuSelected, true);
      app.LoadalignmentfileMenu.Tooltip = {'Load an alignment file, see the Alignment tab for details'};
      app.LoadalignmentfileMenu.Text = 'Load alignment file';
      app.LoadacameraprofileMenu = uimenu(app.DataMenu);
      app.LoadacameraprofileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadacameraprofileMenuSelected, true);
      app.LoadacameraprofileMenu.Tooltip = {'Load a text file that specifies the camera intrinsic properties'};
      app.LoadacameraprofileMenu.Text = 'Load a camera profile';
      app.SetimageoriginMenu = uimenu(app.DataMenu);
      app.SetimageoriginMenu.MenuSelectedFcn = createCallbackFcn(app, @SetimageoriginMenuSelected, true);
      app.SetimageoriginMenu.Tooltip = {'Set the image coordinate origin, either in the upper left corner (typical) or lower left corner (DLTdv7 & easyWand) [not ready yet]'};
      app.SetimageoriginMenu.Enable = 'off';
      app.SetimageoriginMenu.Text = 'Set image origin';
      app.OperationsMenu = uimenu(app.easyWandUIFigure);
      app.OperationsMenu.Enable = 'off';
      app.OperationsMenu.Text = 'Operations';
      app.computecalibrationMenu = uimenu(app.OperationsMenu);
      app.computecalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @computecalibrationMenuSelected, true);
      app.computecalibrationMenu.Tooltip = {'Run the wand calibration operation for the primary cameras'};
      app.computecalibrationMenu.Accelerator = 'g';
      app.computecalibrationMenu.Text = 'compute calibration';
      app.clearoutlierdesignationsMenu = uimenu(app.OperationsMenu);
      app.clearoutlierdesignationsMenu.MenuSelectedFcn = createCallbackFcn(app, @clearoutlierdesignationsMenuSelected, true);
      app.clearoutlierdesignationsMenu.Tooltip = {'Make all points inliers'};
      app.clearoutlierdesignationsMenu.Text = 'clear outlier designations';
      app.identifyoutliersandrecalibrateMenu = uimenu(app.OperationsMenu);
      app.identifyoutliersandrecalibrateMenu.MenuSelectedFcn = createCallbackFcn(app, @identifyoutliersandrecalibrateMenuSelected, true);
      app.identifyoutliersandrecalibrateMenu.Tooltip = {'Makes the worst 5% of the paired & unpaired points "outliers" and re-runs the calibration.'};
      app.identifyoutliersandrecalibrateMenu.Enable = 'off';
      app.identifyoutliersandrecalibrateMenu.Accelerator = 'e';
      app.identifyoutliersandrecalibrateMenu.Text = 'identify outliers and recalibrate';
      app.restoreoriginalcameraparametersMenu = uimenu(app.OperationsMenu);
      app.restoreoriginalcameraparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @restoreoriginalcameraparametersMenuSelected, true);
      app.restoreoriginalcameraparametersMenu.Tooltip = {'Restores the camera parameters to their value at the start of the last calibraiton attempt'};
      app.restoreoriginalcameraparametersMenu.Enable = 'off';
      app.restoreoriginalcameraparametersMenu.Accelerator = 'r';
      app.restoreoriginalcameraparametersMenu.Text = 'restore original camera parameters';
      app.clearmessagesMenu = uimenu(app.OperationsMenu);
      app.clearmessagesMenu.MenuSelectedFcn = createCallbackFcn(app, @clearmessagesMenuSelected, true);
      app.clearmessagesMenu.Tooltip = {'Clears the text in the messages pane below the calibration scene'};
      app.clearmessagesMenu.Text = 'clear messages';
      app.HelpMenu = uimenu(app.easyWandUIFigure);
      app.HelpMenu.Text = 'Help';
      app.AboutMenu = uimenu(app.HelpMenu);
      app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
      app.AboutMenu.Tooltip = {'Information on simpleWand'};
      app.AboutMenu.Text = 'About';
      app.LicenseMenu = uimenu(app.HelpMenu);
      app.LicenseMenu.MenuSelectedFcn = createCallbackFcn(app, @LicenseMenuSelected, true);
      app.LicenseMenu.Tooltip = {'Source code license'};
      app.LicenseMenu.Text = 'License';
      app.ManualMenu = uimenu(app.HelpMenu);
      app.ManualMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualMenuSelected, true);
      app.ManualMenu.Tooltip = {'Open the online manual in a web browser'};
      app.ManualMenu.Text = 'Manual';
      app.CheckforupdateMenu = uimenu(app.HelpMenu);
      app.CheckforupdateMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckforupdateMenuSelected, true);
      app.CheckforupdateMenu.Tooltip = {'Check the github repository to see if a new version is available.'};
      app.CheckforupdateMenu.Text = 'Check for update';
      app.GeteasySBAsourcecodeMenu = uimenu(app.HelpMenu);
      app.GeteasySBAsourcecodeMenu.MenuSelectedFcn = createCallbackFcn(app, @GeteasySBAsourcecodeMenuSelected, true);
      app.GeteasySBAsourcecodeMenu.Tooltip = {'Brings up a save dialog for where to save a zip archive of the easySBA source code'};
      app.GeteasySBAsourcecodeMenu.Text = 'Get easySBA source code';
      app.Getefficient_pnpsourcecodeMenu = uimenu(app.HelpMenu);
      app.Getefficient_pnpsourcecodeMenu.MenuSelectedFcn = createCallbackFcn(app, @Getefficient_pnpsourcecodeMenuSelected, true);
      app.Getefficient_pnpsourcecodeMenu.Tooltip = {'Brings up a save dialog for where to save a zip archive of the efficient_PNP source code'};
      app.Getefficient_pnpsourcecodeMenu.Text = 'Get efficient_pnp source code';
      app.plotAxes = uiaxes(app.easyWandUIFigure);
      title(app.plotAxes, 'Calibration scene')
      xlabel(app.plotAxes, 'X')
      ylabel(app.plotAxes, 'Y')
      app.cameraTable = uitable(app.easyWandUIFigure);
      app.cameraTable.ColumnName = {'Cam'; 'Primary'; 'Horiz. Principal point'; 'Vertical Principal point'; 'Focal length'; 'Distortion r^2'; 'Distortion r^4'; 'Distortion r^6'};
      app.cameraTable.RowName = {};
      app.cameraTable.ColumnEditable = [false true true true true true true true];
      app.TabGroup = uitabgroup(app.easyWandUIFigure);
      app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
      app.PairedpointsTab = uitab(app.TabGroup);
      app.PairedpointsTab.Tooltip = {'Tab showing paired point information'};
      app.PairedpointsTab.Title = 'Paired points';
      app.InterpointdistanceEditFieldLabel = uilabel(app.PairedpointsTab);
      app.InterpointdistanceEditFieldLabel.Text = 'Inter-point distance';
      app.InterpointdistanceEditField = uieditfield(app.PairedpointsTab, 'numeric');
      app.InterpointdistanceEditField.Limits = [0 Inf];
      app.InterpointdistanceEditField.Value = 1;
      app.pairedPointsTable = uitable(app.PairedpointsTab);
      app.pairedPointsTable.ColumnName = {'ID'; 'u'; 'v'; 'x'; 'y'; 'z'; 'rmse'; 'wand length'; 'outlier'};
      app.pairedPointsTable.RowName = {};
      app.pairedPointsTable.ColumnSortable = true;
      app.pairedPointsTable.ColumnEditable = [false false false false false false false false true];
      app.pairedPointsTable.CellEditCallback = createCallbackFcn(app, @pairedPointsTableCellEdit, true);
      app.PairedpointsfileEditFieldLabel = uilabel(app.PairedpointsTab);
      app.PairedpointsfileEditFieldLabel.Text = 'Paired points file:';
      app.PairedpointsfileEditField = uieditfield(app.PairedpointsTab, 'text');
      app.PairedpointsfileEditField.Editable = 'off';
      app.PairedpointsfileEditField.Tooltip = {'Path and filename of the paired points file'};
      app.PairedpointsfileEditField.Value = 'none';
      app.ShowpointsfromDropDown_2Label = uilabel(app.PairedpointsTab);
      app.ShowpointsfromDropDown_2Label.Text = 'Show points from:';
      app.ShowpointsfromDropDown_2 = uidropdown(app.PairedpointsTab);
      app.ShowpointsfromDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ShowpointsfromDropDown_2ValueChanged, true);
      app.UnpairedpointsTab = uitab(app.TabGroup);
      app.UnpairedpointsTab.Tooltip = {'Tab showing unpaired point information'};
      app.UnpairedpointsTab.Title = 'Unpaired points';
      app.unpairedPointsTable = uitable(app.UnpairedpointsTab);
      app.unpairedPointsTable.ColumnName = {'ID'; 'u'; 'v'; 'x'; 'y'; 'z'; 'error'; 'outlier'};
      app.unpairedPointsTable.RowName = {};
      app.unpairedPointsTable.ColumnSortable = true;
      app.unpairedPointsTable.ColumnEditable = [false false false false false false false true];
      app.unpairedPointsTable.CellEditCallback = createCallbackFcn(app, @unpairedPointsTableCellEdit, true);
      app.unpairedPointsTable.Tooltip = {'Mark points as outliers if desired.'};
      app.UnpairedpointsfileEditFieldLabel = uilabel(app.UnpairedpointsTab);
      app.UnpairedpointsfileEditFieldLabel.Text = 'Unpaired points file:';
      app.UnpairedpointsfileEditField = uieditfield(app.UnpairedpointsTab, 'text');
      app.UnpairedpointsfileEditField.Editable = 'off';
      app.UnpairedpointsfileEditField.Tooltip = {'Path and filename of the unpaired points file'};
      app.UnpairedpointsfileEditField.Value = 'none';
      app.ShowpointsfromDropDownLabel = uilabel(app.UnpairedpointsTab);
      app.ShowpointsfromDropDownLabel.Text = 'Show points from:';
      app.ShowpointsfromDropDown = uidropdown(app.UnpairedpointsTab);
      app.ShowpointsfromDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowpointsfromDropDownValueChanged, true);
      app.AlignmentPointsTab = uitab(app.TabGroup);
      app.AlignmentPointsTab.Tooltip = {'Tab with the different alignment options for the 3D scene'};
      app.AlignmentPointsTab.Title = 'Alignment';
      app.AlignmenttypeDropDownLabel = uilabel(app.AlignmentPointsTab);
      app.AlignmenttypeDropDownLabel.Text = 'Alignment type';
      app.AlignmenttypeDropDown = uidropdown(app.AlignmentPointsTab);
      app.AlignmenttypeDropDown.Items = {'4 point axis (origin, +x, +y, +z)', '3 point axis (origin, +x, +y)', 'plane', 'gravity', 'camera azimuth & elevation', '3+ known 3D points'};
      app.AlignmenttypeDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
      app.AlignmenttypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AlignmenttypeDropDownOpening, true);
      app.AlignmenttypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AlignmenttypeDropDownValueChanged, true);
      app.AlignmenttypeDropDown.Value = '5';
      app.AlignmentfileEditFieldLabel = uilabel(app.AlignmentPointsTab);
      app.AlignmentfileEditFieldLabel.Text = 'Alignment file:';
      app.AlignmentfileEditField = uieditfield(app.AlignmentPointsTab, 'text');
      app.AlignmentfileEditField.Editable = 'off';
      app.AlignmentfileEditField.Tooltip = {'Path and filename of the unpaired points file'};
      app.AlignmentfileEditField.Value = 'none';
      app.AlignmentsettingsPanel = uipanel(app.AlignmentPointsTab);
      app.AlignmentsettingsPanel.Title = 'Alignment settings';
      app.CamerarecordingfrequencyHzEditFieldLabel = uilabel(app.AlignmentsettingsPanel);
      app.CamerarecordingfrequencyHzEditFieldLabel.Visible = 'off';
      app.CamerarecordingfrequencyHzEditFieldLabel.Text = 'Camera recording frequency (Hz)';
      app.CamerarecordingfrequencyHzEditField = uieditfield(app.AlignmentsettingsPanel, 'numeric');
      app.CamerarecordingfrequencyHzEditField.Limits = [0.0001 Inf];
      app.CamerarecordingfrequencyHzEditField.Visible = 'off';
      app.CamerarecordingfrequencyHzEditField.Tooltip = {'Specify the recording frequency for aligning to gravity from a trajectory'};
      app.CamerarecordingfrequencyHzEditField.Value = 100;
      app.AzimuthangleofthelastcameradegreesEditFieldLabel = uilabel(app.AlignmentsettingsPanel);
      app.AzimuthangleofthelastcameradegreesEditFieldLabel.Visible = 'off';
      app.AzimuthangleofthelastcameradegreesEditFieldLabel.Text = 'Azimuth angle of the last camera (degrees)';
      app.AzimuthangleofthelastcameradegreesEditField = uieditfield(app.AlignmentsettingsPanel, 'numeric');
      app.AzimuthangleofthelastcameradegreesEditField.Limits = [-360 360];
      app.AzimuthangleofthelastcameradegreesEditField.Visible = 'off';
      app.AzimuthangleofthelastcameradegreesEditField.Tooltip = {'Specify the azimuth of the last camera in degrees.'};
      app.ElevationangleofthelastcameradegreesEditFieldLabel = uilabel(app.AlignmentsettingsPanel);
      app.ElevationangleofthelastcameradegreesEditFieldLabel.Visible = 'off';
      app.ElevationangleofthelastcameradegreesEditFieldLabel.Text = 'Elevation angle of the last camera (degrees)';
      app.ElevationangleofthelastcameradegreesEditField = uieditfield(app.AlignmentsettingsPanel, 'numeric');
      app.ElevationangleofthelastcameradegreesEditField.Limits = [-360 360];
      app.ElevationangleofthelastcameradegreesEditField.Visible = 'off';
      app.ElevationangleofthelastcameradegreesEditField.Tooltip = {'Enter the elevation angle of the last camera in degrees'};
      app.NosettingsforthisalignmenttypeLabel = uitextarea(app.AlignmentsettingsPanel);
      app.NosettingsforthisalignmenttypeLabel.Editable = 'off';
      app.NosettingsforthisalignmenttypeLabel.Value = {'No settings for this alignment type.'};
      app.alignmentPointsTable = uitable(app.AlignmentPointsTab);
      app.alignmentPointsTable.ColumnName = {'ID'; 'u'; 'v'; 'x'; 'y'; 'z'; 'error'};
      app.alignmentPointsTable.RowName = {};
      app.alignmentPointsTable.ColumnSortable = true;
      app.ShowalignmentpointsfromDropDownLabel = uilabel(app.AlignmentPointsTab);
      app.ShowalignmentpointsfromDropDownLabel.Text = 'Show alignment points from:';
      app.ShowalignmentpointsfromDropDown = uidropdown(app.AlignmentPointsTab);
      app.ShowalignmentpointsfromDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowalignmentpointsfromDropDownValueChanged, true);
      app.ScaleTab = uitab(app.TabGroup);
      app.ScaleTab.Title = 'Scale';
      app.SetscenescalefrommeasuredcameracameradistancesCheckBox = uicheckbox(app.ScaleTab);
      app.SetscenescalefrommeasuredcameracameradistancesCheckBox.Tooltip = {'Option to set the scene scale from camera distances instead of inter-paired-point distance'};
      app.SetscenescalefrommeasuredcameracameradistancesCheckBox.Text = 'Set scene scale from measured camera-camera distances';
      app.CameraDistanceTable = uitable(app.ScaleTab);
      app.CameraDistanceTable.ColumnName = {'Camera 1 ID'; 'Camera 2 ID'; 'camera-camera distance'};
      app.CameraDistanceTable.RowName = {};
      app.CameraDistanceTable.ColumnSortable = true;
      app.CameraDistanceTable.ColumnEditable = [false false true];
      app.CameraDistanceTable.Tooltip = {'Fill in the distances between at least one pair of cameras to enable setting the scale from this information'};
      app.OptimizationTab = uitab(app.TabGroup);
      app.OptimizationTab.Title = 'Optimization';
      app.DescriptionofoptimizationoperationsTextAreaLabel = uilabel(app.OptimizationTab);
      app.DescriptionofoptimizationoperationsTextAreaLabel.Text = 'Description of optimization operations';
      app.DescriptionofoptimizationoperationsTextArea = uitextarea(app.OptimizationTab);
      app.DescriptionofoptimizationoperationsTextArea.Value = {'These optimization settings control how the Sparse Bundle Adjustment library will modify the camera intrinsic parameters (focal length, etc.) and lens distortion to get a better fit between the cameras and observations. Use the Operations menu "Restore camera ..." command to go back to the original values if the optimization goes poorly.'; ''; 'SBA cannot optimize the intrinsics in 2 camera projects, but if you have external scale or orientation information such as a wand length, set of known camera distances, or known 3D points you can manually adjust the focal lengths to give you the best fit to those metrics.'; ''; 'Optimizing the lens distortion parameters typically works best when you have more than a 1000 data points. Also, although the model contains tangential distortion it isn''t shown in the table below because it''s almost always 0 for modern cameras used in scientific research. Note that the distortion terms are additive, estimating the r^4 term means also estimating the r^2 term.'};
      app.IntrinsicparamatersDropDownLabel = uilabel(app.OptimizationTab);
      app.IntrinsicparamatersDropDownLabel.Text = 'Intrinsic paramaters';
      app.IntrinsicparamatersDropDown = uidropdown(app.OptimizationTab);
      app.IntrinsicparamatersDropDown.Items = {'None', 'Focal Lengths only', 'FLs and Principal Points'};
      app.IntrinsicparamatersDropDown.ItemsData = [5 4 2];
      app.IntrinsicparamatersDropDown.Enable = 'off';
      app.IntrinsicparamatersDropDown.Tooltip = {'Set the optimization mode for camera intrinsic parameters; only available for projects with 3+ cameras'};
      app.IntrinsicparamatersDropDown.Value = 5;
      app.LensdistortionDropDownLabel = uilabel(app.OptimizationTab);
      app.LensdistortionDropDownLabel.Text = 'Lens distortion';
      app.LensdistortionDropDown = uidropdown(app.OptimizationTab);
      app.LensdistortionDropDown.Items = {'None', 'Radial r^2', '  + Radial r^4', '    + Tangential kc3', '    + Tangential kc4', '+ Radial r^6'};
      app.LensdistortionDropDown.ItemsData = [5 4 3 2 1 0];
      app.LensdistortionDropDown.Tooltip = {'Set the optimzation mode for the lens distortion parameters'};
      app.LensdistortionDropDown.Value = 5;
      app.msgTxtArea = uitextarea(app.easyWandUIFigure);
      % Show the figure after all components are created
      app.easyWandUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = easyWand6

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.easyWandUIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.easyWandUIFigure)
    end
  end
end